classdef CryoReader1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        magnificationApxEditField       matlab.ui.control.NumericEditField
        magnificationApxEditFieldLabel  matlab.ui.control.Label
        useAngstromCheckBox             matlab.ui.control.CheckBox
        ClearButton                     matlab.ui.control.Button
        InvertCheckBox_3                matlab.ui.control.CheckBox
        SecondaryImageButton            matlab.ui.control.Button
        SelectadditionalimagesListBox   matlab.ui.control.ListBox
        SelectadditionalimagesListBoxLabel  matlab.ui.control.Label
        FoldersLabel                    matlab.ui.control.Label
        cryoListBox                     matlab.ui.control.ListBox
        TabGroup2                       matlab.ui.container.TabGroup
        FolderTab                       matlab.ui.container.Tab
        FileTypeDropDown                matlab.ui.control.DropDown
        FileTypeDropDownLabel           matlab.ui.control.Label
        actualPath                      matlab.ui.control.TextArea
        ChangeFolderButton              matlab.ui.control.Button
        ReloadButton                    matlab.ui.control.Button
        OrganizeTab                     matlab.ui.container.Tab
        addonEditField                  matlab.ui.control.EditField
        addonEditFieldLabel             matlab.ui.control.Label
        InvertCheckBox                  matlab.ui.control.CheckBox
        OrganizeforMathematicaButton    matlab.ui.control.Button
        AnalysisFolderButton            matlab.ui.control.Button
        analysisPath                    matlab.ui.control.TextArea
        PresetsTab                      matlab.ui.container.Tab
        SavePresetsButton               matlab.ui.control.Button
        LoadPresetsButton               matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        StatusEditField                 matlab.ui.control.EditField
        StatusEditFieldLabel            matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        imageTab                        matlab.ui.container.Tab
        imageEditField                  matlab.ui.control.EditField
        imageEditFieldLabel             matlab.ui.control.Label
        InvertCheckBox_2                matlab.ui.control.CheckBox
        externalfigureCheckBox          matlab.ui.control.CheckBox
        ROIupdate                       matlab.ui.control.Button
        yCEditField                     matlab.ui.control.NumericEditField
        yCEditFieldLabel                matlab.ui.control.Label
        xCEditField                     matlab.ui.control.NumericEditField
        xCEditFieldLabel                matlab.ui.control.Label
        radiusEditField                 matlab.ui.control.NumericEditField
        radiusEditFieldLabel            matlab.ui.control.Label
        deleteROIButton                 matlab.ui.control.Button
        TypeDropDown                    matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        ROIButtonAn                     matlab.ui.control.Button
        ImageLoadButton                 matlab.ui.control.Button
        UIAxes_CryoImage                matlab.ui.control.UIAxes
        profilesTab                     matlab.ui.container.Tab
        dEditField_3                    matlab.ui.control.NumericEditField
        dEditField_3Label               matlab.ui.control.Label
        dEditField_2                    matlab.ui.control.NumericEditField
        dEditField_2Label               matlab.ui.control.Label
        dEditField                      matlab.ui.control.NumericEditField
        dEditFieldLabel                 matlab.ui.control.Label
        refineButton                    matlab.ui.control.Button
        ImageEditField                  matlab.ui.control.EditField
        ImageEditFieldLabel             matlab.ui.control.Label
        showlinesegmentsCheckBox        matlab.ui.control.CheckBox
        autosaveCheckBox                matlab.ui.control.CheckBox
        MembraneSpinner                 matlab.ui.control.Spinner
        MembraneSpinnerLabel            matlab.ui.control.Label
        NEditField_4                    matlab.ui.control.NumericEditField
        NEditField_4Label               matlab.ui.control.Label
        CoMradiusAEditField             matlab.ui.control.NumericEditField
        CoMradiusAEditFieldLabel        matlab.ui.control.Label
        addtostatButton                 matlab.ui.control.Button
        extractandalignButton           matlab.ui.control.Button
        sigmaEditField_3                matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        sigmaEditField_2                matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        sigmaEditField                  matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        MeanEditField_3                 matlab.ui.control.NumericEditField
        MeanEditField_3Label            matlab.ui.control.Label
        MeanEditField_2                 matlab.ui.control.NumericEditField
        MeanEditField_2Label            matlab.ui.control.Label
        MeanEditField                   matlab.ui.control.NumericEditField
        MeanEditFieldLabel              matlab.ui.control.Label
        fitButton                       matlab.ui.control.Button
        alignButton                     matlab.ui.control.Button
        profilestotalEditField          matlab.ui.control.NumericEditField
        profilestotalEditFieldLabel     matlab.ui.control.Label
        profilepxEditField              matlab.ui.control.NumericEditField
        profilepxEditFieldLabel         matlab.ui.control.Label
        profilelengthAEditField         matlab.ui.control.NumericEditField
        profilelengthAEditFieldLabel    matlab.ui.control.Label
        segmentpxEditField              matlab.ui.control.NumericEditField
        segmentpxEditFieldLabel         matlab.ui.control.Label
        segmentlengthAEditField         matlab.ui.control.NumericEditField
        segmentlengthAEditFieldLabel    matlab.ui.control.Label
        extractradialprofilesButton     matlab.ui.control.Button
        ProfileSpinner                  matlab.ui.control.Spinner
        ProfileLabel                    matlab.ui.control.Label
        UIAxes_Hist3                    matlab.ui.control.UIAxes
        UIAxes_Hist2                    matlab.ui.control.UIAxes
        UIAxes_Hist1                    matlab.ui.control.UIAxes
        UIAxes_AvgProfile3              matlab.ui.control.UIAxes
        UIAxes_AvgProfile2              matlab.ui.control.UIAxes
        UIAxes_AvgProfile1              matlab.ui.control.UIAxes
        UIAxes_AllProfiles3             matlab.ui.control.UIAxes
        UIAxes_AllProfiles2             matlab.ui.control.UIAxes
        UIAxes_AllProfiles1             matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_CryoImage_4              matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_CryoImage_3              matlab.ui.control.UIAxes
        UIAxes_CryoImage_2              matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        thicknessTab                    matlab.ui.container.Tab
        avglengthEditField              matlab.ui.control.NumericEditField
        avglengthEditFieldLabel         matlab.ui.control.Label
        Tag1excludeCheckBox             matlab.ui.control.CheckBox
        includedLamp                    matlab.ui.control.Lamp
        includedLampLabel               matlab.ui.control.Label
        neglectprofileswexceptionsCheckBox  matlab.ui.control.CheckBox
        MembraneSpinner_2               matlab.ui.control.Spinner
        MembraneSpinner_2Label          matlab.ui.control.Label
        maxConfidenceEditField          matlab.ui.control.NumericEditField
        maxConfidenceEditFieldLabel     matlab.ui.control.Label
        UIAxes_AllProfilesTrans3        matlab.ui.control.UIAxes
        UIAxes_Exceptions3              matlab.ui.control.UIAxes
        UIAxes_Confidence3              matlab.ui.control.UIAxes
        UIAxes_AllProfilesTrans2        matlab.ui.control.UIAxes
        UIAxes_Exceptions2              matlab.ui.control.UIAxes
        UIAxes_Confidence2              matlab.ui.control.UIAxes
        UIAxes_Confidence1              matlab.ui.control.UIAxes
        UIAxes_Exceptions1              matlab.ui.control.UIAxes
        UIAxes_AllProfilesTrans1        matlab.ui.control.UIAxes
        UIAxes_ThicknessProfile3        matlab.ui.control.UIAxes
        UIAxes_ThicknessProfile2        matlab.ui.control.UIAxes
        UIAxes_ThicknessProfile1        matlab.ui.control.UIAxes
        statisticTab                    matlab.ui.container.Tab
        poolresultsCheckBox             matlab.ui.control.CheckBox
        Group3Lamp                      matlab.ui.control.Lamp
        Group3LampLabel                 matlab.ui.control.Label
        Group2Lamp                      matlab.ui.control.Lamp
        Group2LampLabel                 matlab.ui.control.Label
        Group1Lamp                      matlab.ui.control.Lamp
        Group1LampLabel                 matlab.ui.control.Label
        updateButton                    matlab.ui.control.Button
        SaveButton_stat3                matlab.ui.control.Button
        SaveButton_stat2                matlab.ui.control.Button
        SaveButton_stat1                matlab.ui.control.Button
        RefitallCheckBox                matlab.ui.control.CheckBox
        CLEARButton                     matlab.ui.control.Button
        Group3EditField                 matlab.ui.control.EditField
        Group2EditField_2Label          matlab.ui.control.Label
        Group2EditField                 matlab.ui.control.EditField
        Group2EditFieldLabel            matlab.ui.control.Label
        Group1EditField                 matlab.ui.control.EditField
        Group1EditFieldLabel            matlab.ui.control.Label
        Stat3LoadButton                 matlab.ui.control.Button
        Stat2LoadButton                 matlab.ui.control.Button
        Stat1LoadButton                 matlab.ui.control.Button
        batchfitButton                  matlab.ui.control.Button
        MeanEditField_6                 matlab.ui.control.NumericEditField
        MeanEditField_6Label            matlab.ui.control.Label
        sigmaEditField_6                matlab.ui.control.NumericEditField
        stdvLabel_3                     matlab.ui.control.Label
        MeanEditField_5                 matlab.ui.control.NumericEditField
        MeanEditField_5Label            matlab.ui.control.Label
        sigmaEditField_5                matlab.ui.control.NumericEditField
        stdvLabel_2                     matlab.ui.control.Label
        SegmentsEditField_3             matlab.ui.control.NumericEditField
        SegmentsEditField_3Label        matlab.ui.control.Label
        NEditField_3                    matlab.ui.control.NumericEditField
        NEditField_3Label               matlab.ui.control.Label
        FittedEditField_3               matlab.ui.control.NumericEditField
        FittedEditField_3Label          matlab.ui.control.Label
        SegmentsEditField_2             matlab.ui.control.NumericEditField
        SegmentsEditField_2Label        matlab.ui.control.Label
        NEditField_2                    matlab.ui.control.NumericEditField
        NEditField_2Label               matlab.ui.control.Label
        FittedEditField_2               matlab.ui.control.NumericEditField
        FittedEditField_2Label          matlab.ui.control.Label
        sigmaEditField_4                matlab.ui.control.NumericEditField
        stdvLabel                       matlab.ui.control.Label
        MeanEditField_4                 matlab.ui.control.NumericEditField
        MeanEditField_4Label            matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        SegmentsEditField               matlab.ui.control.NumericEditField
        SegmentsEditFieldLabel          matlab.ui.control.Label
        FittedEditField                 matlab.ui.control.NumericEditField
        FittedEditFieldLabel            matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        ResultsEditField                matlab.ui.control.EditField
        ResultsEditFieldLabel           matlab.ui.control.Label
        UIAxes_Avgavg3                  matlab.ui.control.UIAxes
        UIAxes_Avgavg2                  matlab.ui.control.UIAxes
        UIAxes_Avgavg1                  matlab.ui.control.UIAxes
        UIAxes_Membranes3               matlab.ui.control.UIAxes
        UIAxes_Membranes2               matlab.ui.control.UIAxes
        UIAxes_Membranes1               matlab.ui.control.UIAxes
        UIAxes_Hist3_2                  matlab.ui.control.UIAxes
        UIAxes_Hist2_2                  matlab.ui.control.UIAxes
        UIAxes_Hist1_2                  matlab.ui.control.UIAxes
        ToolsTab                        matlab.ui.container.Tab
        newmagnificationApxEditField    matlab.ui.control.NumericEditField
        newmagnificationApxEditFieldLabel  matlab.ui.control.Label
        UpdatewithnewMagnificationButton  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        cryoNfiles % Description
        analysisFileList % Description
        mxCombinePath % Description
        roiC % Description
        roiCArray % Description
        cryoImage % Description
        allProfiles % Description
        allProfilesSegments % Description
        allProfilesSegments2 % Description
        allProfilesSegments3 % Description
        allProfilesSegmentsSmoothed % Description
        allProfilesSegments2Smoothed % Description
        allProfilesSegments3Smoothed % Description
        cryoImageExt % Description
        x % Description
        cryoImage2 % Description
        cryoImage3 % Description
        extraImages % Description

        exceptions % Description
        fitresults1 % Description
        fitresults2 % Description
        fitresults3 % Description
        thicknesses1 % Description
        thicknesses2 % Description
        thicknesses3 % Description
        cryoResults1 % Description
        cryoResults2 % Description
        cryoResults3 % Description
        cryoResultsArray1 % Description
        cryoResultsArray2 % Description
        cryoResultsArray3 % Description
        analysisFileNames % Description
        previousROIs % Description
        stat1 % Description
        stat2 % Description
        stat3 % Description
        cryoProfilesExt % Description
        group1 % Description
        group2 % Description
        group3 % Description
    end
    
    methods (Access = private)
        
        function  fillFileList(app,selpath)
            [fileList, ~] = readFiles(selpath);
            filetype = app.FileTypeDropDown.Value;
            switch filetype
                case 'MRC'
                   cryoFileList = fileList(contains(fileList,".mrc"));
                case 'TIFF'
                   cryoFileList = fileList(contains(fileList,".tiff"));
                case 'MAT'
                    cryoFileList = fileList(contains(fileList,".mat"));
            end
            %folderList = readFolders(selpath)'; 
            app.cryoNfiles=size(cryoFileList,2);
            cryoIndices=1:app.cryoNfiles;
            app.cryoListBox.Items = cryoFileList;
            app.cryoListBox.ItemsData = cryoIndices;
        end
        
        function [resultsout, thicknessA,hout,gfit,bestfit] = fillfitresultsarray(app,fitresults,fitsigmas,fitconfidence,thicknesses)
            % resultsout:  1: 1st Min, 2: Max, 3: 2nd Min, 
            % 4: sigma 1st Min, 5: sigma Max, 6: sigma 2nd Min
            % 7,8: confidence,  9,10: confidence, 11,12: confidence
            %
            numberProfiles = size(fitresults,1);
            resultsout = zeros(numberProfiles,12);
            resultsout(:,1:3)=fitresults;
            resultsout(:,4:6)=fitsigmas;
            resultsout(:,7:12)=fitconfidence;
            thicknessA = thicknesses.*app.magnificationApxEditField.Value;
            exc = analyzeResults(resultsout,app.maxConfidenceEditField.Value);
            [hN, edges] = histcounts(thicknessA(exc,1));
            binsize = (edges(1,2)-edges(1,1));
            edges=edges+0.5*binsize;
            edges = edges(1,1:end-1);
            if size(hN,2) <3
                hN = [hN, 0,0,0];
                edges = [edges, edges(end)+binsize, edges(end)+2*binsize,edges(end)+3*binsize];
            end
            hout = [edges; hN];
            meant = mean(thicknessA(exc,1));
            sdtt = std(thicknessA(exc,1));
            maxN = max(hN);
            options = fitoptions('gauss1');
            options.StartPoint = [maxN, meant, sdtt];
            options.Lower = [0 0 0.5];
            options.Upper = [2*maxN 60 100];
            gfit = fit(edges',hN','gauss1',options);
            edgesF = [edges(1,1):0.1:edges(1,end)];
            y = feval(gfit,edgesF);
            bestfit = [edgesF;y'];
        end
        
        
        function extractProfiles(app)
            circleCenter = [app.xCEditField.Value, app.yCEditField.Value];
            circleRadius = app.radiusEditField.Value;
            segmentLengthPx = app.segmentpxEditField.Value;
            profilepx = app.profilepxEditField.Value;
            [app.allProfiles,app.allProfilesSegments,croppedI] = extractRadialProfiles(app.cryoImage,circleCenter,circleRadius,segmentLengthPx,profilepx);
            % add to results
            app.group1 = true;
            app.Group1Lamp.Color = 'green';
            app.cryoResults1 = addCryoResultsProfiles(app,app.cryoResults1,croppedI,app.allProfiles,app.allProfilesSegments);
            %
            segmentsN = size(app.allProfilesSegments,1);
            app.profilestotalEditField.Value = segmentsN;
            profileLength = size(app.allProfilesSegments,2);
            app.x = (1:profileLength);
            if app.useAngstromCheckBox.Value
                app.x = app.x .*app.magnificationApxEditField.Value;
            end
            app.ProfileSpinner.Limits = [1, segmentsN];
            app.ProfileSpinner.Value =1;
            app.ProfileSpinner.Enable = true;
            app.MembraneSpinner.Value = 0;
            app.allProfilesSegmentsSmoothed = smoothdata(app.allProfilesSegments,2,'gaussian',5);
            image(app.UIAxes_AllProfiles1,app.allProfilesSegmentsSmoothed,'CDataMapping','scaled');
            drawnow
%             figure;
%             image(app.allProfilesSegmentsSmoothed,'CDataMapping','scaled');
            profileSelect = app.allProfilesSegments(1,:);
            profileSelectSmooth = app.allProfilesSegmentsSmoothed(1,:);
            plot(app.UIAxes,app.x,profileSelect,'b',app.x,profileSelectSmooth,'r');
            image(app.UIAxes_CryoImage_2,croppedI,'CDataMapping','scaled');

            if ~isempty(app.extraImages)
                [allProfiles2, app.allProfilesSegments2,croppedI2]=extractRadialProfiles(app.cryoImage2,circleCenter,circleRadius,segmentLengthPx,profilepx);
                            % add to results
                app.group2 = true;
                app.Group2Lamp.Color = 'green';
                app.cryoResults2 = addCryoResultsProfiles(app,app.cryoResults2,croppedI2,allProfiles2,app.allProfilesSegments2);
                %
                app.allProfilesSegments2Smoothed = smoothdata(app.allProfilesSegments2,2,'gaussian',5);
                image(app.UIAxes_AllProfiles2,app.allProfilesSegments2Smoothed,'CDataMapping','scaled');
                drawnow
                profileSelect2 = app.allProfilesSegments2(1,:);
                profileSelect2Smooth = app.allProfilesSegments2Smoothed(1,:);
                plot(app.UIAxes_2,app.x,profileSelect2,'b',app.x,profileSelect2Smooth,'r');
                image(app.UIAxes_CryoImage_3,croppedI2,'CDataMapping','scaled');
                if size(app.extraImages,2)==2
                    [allProfiles3, app.allProfilesSegments3,croppedI3]=extractRadialProfiles(app.cryoImage3,circleCenter,circleRadius,segmentLengthPx,profilepx);
                                                % add to results
                    app.group3 = true;
                    app.Group3Lamp.Color = 'green';
                    app.cryoResults3 = addCryoResultsProfiles(app,app.cryoResults3,croppedI3,allProfiles3,app.allProfilesSegments3);
                    %
                    app.allProfilesSegments3Smoothed = smoothdata(app.allProfilesSegments3,2,'gaussian',5);
                    image(app.UIAxes_AllProfiles3,app.allProfilesSegments3Smoothed,'CDataMapping','scaled');
                    drawnow
                    profileSelect3 = app.allProfilesSegments3(1,:);
                    profileSelect3Smooth = app.allProfilesSegments3Smoothed(1,:);
                    plot(app.UIAxes_3,app.x,profileSelect3,'b',app.x,profileSelect3Smooth,'r');
                    image(app.UIAxes_CryoImage_4,croppedI3,'CDataMapping','scaled');
                end
            end
        end
        
        function aligneProfiles(app)
                        % build moving average of primary profiles over ~15 Angstroem
            alignmentProfiles = profilesMvgAvg(app.allProfiles,93);
            [alignmentPixel,fitorder] = alignmentParameters(alignmentProfiles);
            app.cryoResults1.alignmentPixel = alignmentPixel;
            % estimate Center of Mass radius
            circleCenterPx = [app.xCEditField.Value, app.yCEditField.Value];
            circleRadiusPx = app.radiusEditField.Value;
            segmentLengthPx = app.segmentpxEditField.Value;
            profilepx = app.profilepxEditField.Value;
            [radiusCoM, ~, ~] = calcCenterOfMass1(alignmentPixel,circleCenterPx,circleRadiusPx,segmentLengthPx,profilepx);
            app.CoMradiusAEditField.Value = app.magnificationApxEditField.Value * radiusCoM;
            %
            % aligne primary image profiles
            [app.allProfilesSegments,app.exceptions] = alignProfiles(app.allProfilesSegments,alignmentPixel,fitorder);
            app.allProfilesSegmentsSmoothed = smoothdata(app.allProfilesSegments,2,"gaussian",5);
            image(app.UIAxes_AllProfiles1,app.allProfilesSegmentsSmoothed,'CDataMapping','scaled');
            app.x = [1:81];
            if app.useAngstromCheckBox.Value
                app.x = app.x .*app.magnificationApxEditField.Value;
            end
            avgProfile1 = mean(app.allProfilesSegments,1);
            plot(app.UIAxes_AvgProfile1,app.x,avgProfile1);
            drawnow
            app.cryoResults1.status = 2;  %profiles aligned
            app.cryoResults1.allprofilesF = app.allProfilesSegments;
            app.cryoResults1.avgProfile = avgProfile1;
            app.cryoResults1.exceptions = app.exceptions;
            app.cryoResults1.vesicleCoMradius = radiusCoM * app.magnificationApxEditField.Value;
            app.MembraneSpinner.Value = 0;
            if app.group2
                [app.allProfilesSegments2,~] = alignProfiles(app.allProfilesSegments2,alignmentPixel,fitorder);
                app.allProfilesSegments2Smoothed = smoothdata(app.allProfilesSegments2,2,"gaussian",5);
                image(app.UIAxes_AllProfiles2,app.allProfilesSegments2Smoothed,'CDataMapping','scaled');
                avgProfile2 = mean(app.allProfilesSegments2,1);
                plot(app.UIAxes_AvgProfile2,app.x,avgProfile2);
                drawnow
                app.cryoResults2.status = 2;  %profiles aligned
                app.cryoResults2.alignmentPixel = alignmentPixel;
                app.cryoResults2.allprofilesF = app.allProfilesSegments2;
                app.cryoResults2.avgProfile = avgProfile2;
                app.cryoResults2.exceptions = app.exceptions;
                app.cryoResults2.vesicleCoMradius = radiusCoM;
            end
            if app.group3
                [app.allProfilesSegments3,~] = alignProfiles(app.allProfilesSegments3,alignmentPixel,fitorder);
                app.allProfilesSegments3Smoothed = smoothdata(app.allProfilesSegments3,2,"gaussian",5);
                image(app.UIAxes_AllProfiles3,app.allProfilesSegments3Smoothed,'CDataMapping','scaled');
                avgProfile3 = mean(app.allProfilesSegments3,1);
                plot(app.UIAxes_AvgProfile3,app.x,avgProfile3);
                drawnow
                app.cryoResults3.status = 2;  %profiles aligned
                app.cryoResults3.alignmentPixel = alignmentPixel;
                app.cryoResults3.allprofilesF = app.allProfilesSegments3;
                app.cryoResults3.avgProfile = avgProfile3;
                app.cryoResults3.exceptions = app.exceptions;
                app.cryoResults3.vesicleCoMradius = radiusCoM;
            end
            
        end
        
        function fitProfiles(app)
            app.StatusEditField.Value = 'Fitting profiles 1 ...';
            drawnow
            [fitresults,fitsigmas,fitconfidence,thicknesses] = fitProfiles1p(app.allProfilesSegments,true);
            [app.fitresults1, app.thicknesses1,histo,gfit,bestfit] = fillfitresultsarray(app,fitresults,fitsigmas,fitconfidence,thicknesses);
            plot(app.UIAxes_Hist1,histo(1,:)',histo(2,:)','o',bestfit(1,:)',bestfit(2,:)');
            nFit = sum(~isnan(app.thicknesses1(:,1)));
            if nFit ~=0
                stderr = gfit.b1/sqrt(nFit);
            else
                stderr = gfit.c1;
            end
            app.MeanEditField.Value = gfit.b1;
            app.sigmaEditField.Value = stderr;
            app.cryoResults1.status = 3;  %profiles fitted
            app.cryoResults1.fitresults = app.fitresults1;
            app.cryoResults1.thicknesses = app.thicknesses1;
            app.cryoResults1.thicknessDistrMean = gfit.b1;
            app.cryoResults1.thicknessDistrErr = stderr;
            app.cryoResults1.thicknessN = nFit;
            app.cryoResults1.histo = histo;
            app.cryoResults1.bestfit = bestfit;
            plot(app.UIAxes_ThicknessProfile1,app.thicknesses1(:,1));
            app.cryoResultsArray1(1,app.MembraneSpinner.Value) = app.cryoResults1;
            %app.MembraneSpinner.Value = 0;
            if app.group2
                app.StatusEditField.Value = 'Fitting profiles 2 ...';
                drawnow
                [fitresults,fitsigmas,fitconfidence,thicknesses] = fitProfiles1p(app.allProfilesSegments2,true);
                [app.fitresults2, app.thicknesses2,histo,gfit,bestfit] = fillfitresultsarray(app,fitresults,fitsigmas,fitconfidence,thicknesses);
                plot(app.UIAxes_Hist2,histo(1,:)',histo(2,:)','o',bestfit(1,:)',bestfit(2,:)');
                nFit = sum(~isnan(app.thicknesses1(:,1)));
                if nFit ~=0
                    stderr = gfit.b1/sqrt(nFit);
                else
                    stderr = gfit.c1;
                end
                app.MeanEditField_2.Value = gfit.b1;
                app.sigmaEditField_2.Value = stderr;
                app.cryoResults2.status = 3;  %profiles fitted
                app.cryoResults2.fitresults = app.fitresults2;
                app.cryoResults2.thicknesses = app.thicknesses2;
                app.cryoResults2.thicknessDistrMean = gfit.b1;
                app.cryoResults2.thicknessDistrErr = stderr;
                app.cryoResults2.thicknessN = nFit;
                app.cryoResults2.histo = histo;
                app.cryoResults2.bestfit = bestfit;
                plot(app.UIAxes_ThicknessProfile2,app.thicknesses2(:,1));
                app.cryoResultsArray2(1,app.MembraneSpinner.Value) = app.cryoResults2;
            end
            if app.group3
                app.StatusEditField.Value = 'Fitting profiles 3 ...';
                drawnow
                [fitresults,fitsigmas,fitconfidence,thicknesses] = fitProfiles1p(app.allProfilesSegments3,true);
                [app.fitresults3, app.thicknesses3,histo,gfit,bestfit] = fillfitresultsarray(app,fitresults,fitsigmas,fitconfidence,thicknesses);
                plot(app.UIAxes_Hist3,histo(1,:)',histo(2,:)','o',bestfit(1,:)',bestfit(2,:)');
                nFit = sum(~isnan(app.thicknesses1(:,1)));
                if nFit ~=0
                    stderr = gfit.b1/sqrt(nFit);
                else
                    stderr = gfit.c1;
                end
                app.MeanEditField_3.Value = gfit.b1;
                app.sigmaEditField_3.Value = stderr;
                app.cryoResults3.status = 3;  %profiles fitted
                app.cryoResults3.fitresults = app.fitresults3;
                app.cryoResults3.thicknesses = app.thicknesses3;
                app.cryoResults3.thicknessDistrMean = gfit.b1;
                app.cryoResults3.thicknessDistrErr = stderr;
                app.cryoResults3.thicknessN = nFit;
                app.cryoResults2.histo = histo;
                app.cryoResults2.bestfit = bestfit;
                plot(app.UIAxes_ThicknessProfile3,app.thicknesses3(:,1));
                app.cryoResultsArray3(1,app.MembraneSpinner.Value) = app.cryoResults3;
            end
            
            app.StatusEditField.Value = 'Done';
            drawnow
        end
        
        function cryoresultstr = addCryoResultsProfiles(app,cryoresultstr,croppedI,allprofiles,allprofilesF)
            cryoresultstr.status = 1;  %profiles extracted
            cryoresultstr.magnification = app.magnificationApxEditField.Value;
            cryoresultstr.vesicleposition = [app.xCEditField.Value,app.yCEditField.Value,app.radiusEditField.Value];
            %
            cryoresultstr.vesicleimage = croppedI;
            cryoresultstr.profilevariables = [app.segmentlengthAEditField.Value, app.segmentpxEditField.Value,...
            app.profilelengthAEditField.Value,app.profilepxEditField.Value,app.profilestotalEditField.Value];  %[segmentA, segmentPx, profilelengthA, profilelengthPx, Nprofiles]
            cryoresultstr.allprofiles = allprofiles;
            cryoresultstr.allprofilesF = allprofilesF;
        end
        
        
        function updateStat(app)
            membranesN = size(app.cryoResultsArray1,2);
            app.NEditField.Value = membranesN;
            app.NEditField_4.Value = membranesN;
            fitsN =0;
            profilesN = 0;
            allthicknesses = [];
            % allthicknessesL1 = [];
            % allthicknessesL2 = [];
            avg = zeros(1,81);
            memR = [];
            memT = [];
            memE = [];
            for index = 1:membranesN
                res = app.cryoResultsArray1(1,index);
                exclude = logical(1 & res.tag);
                if (res.status >= 3) && (~exclude)
                    fitsN = fitsN+1;
                    exc = analyzeResults(res.fitresults,app.maxConfidenceEditField.Value);
                    if app.neglectprofileswexceptionsCheckBox.Value
                        exc = exc | res.exceptions';
                    end
                    currentThicknesses = res.thicknesses(~exc,1)';
                    nResults = size(currentThicknesses,2);
                    % currentThicknessesL1 = res.thicknesses(~exc,3);
                    % currentThicknessesL2 = res.thicknesses(~exc,4);
                    if size(nResults) > 0
                        currentMean = mean(currentThicknesses);
                        currentSTDE = std(currentThicknesses)/sqrt(nResults);
                        allthicknesses = [allthicknesses, currentThicknesses];
                        % allthicknessesL1 = [allthicknessesL1, currentThicknessesL1];
                        % allthicknessesL1 = [allthicknessesL2, currentThicknessesL2];
                        memR = [memR, res.vesicleCoMradius];
                        memT = [memT, currentMean];
                        memE = [memE, currentSTDE];
                    end
                end
                app.stat1.allthicknesses = allthicknesses; 
                app.stat1.memR = memR;
                app.stat1.memT = memT;
                app.stat1.memE = memE;
                app.FittedEditField.Value = fitsN;
                if res.status >= 2
                    profilesN = profilesN+size(res.allprofilesF,1);
                    avg = avg + res.avgProfile;
                end
            end
            app.SegmentsEditField.Value = profilesN;
            xa = [1 : size(avg,2)];
            xa = xa.*app.magnificationApxEditField.Value;
            app.stat1.avg = avg;
            app.stat1.xa = xa;
            plot(app.UIAxes_Avgavg1,xa,avg);
            if fitsN >0
                [gfit, bestfit, histo] = fitThicknessDistribution(allthicknesses);
                plot(app.UIAxes_Hist1_2,histo(1,:)',histo(2,:)','o',bestfit(1,:)',bestfit(2,:)');
                ci = confint(gfit,0.95);
                app.MeanEditField_4.Value = gfit.b1;
                app.sigmaEditField_4.Value = gfit.c1;
                errorbar(app.UIAxes_Membranes1,memR,memT,memE,'o');
            end
            %2
            membranesN = size(app.cryoResultsArray2,2);
            if membranesN > 0
                app.NEditField_2.Value = membranesN;
                fitsN =0;
                profilesN = 0;
                allthicknesses = [];
                avg = zeros(1,81);
                memR = [];
                memT = [];
                memE = [];
                for index = 1:membranesN
                    res = app.cryoResultsArray2(1,index);
                    exclude = logical(1 & res.tag);
                    if (res.status >= 3) && (~exclude)
                        fitsN = fitsN+1;
                        exc = analyzeResults(res.fitresults,app.maxConfidenceEditField.Value);
                        if app.neglectprofileswexceptionsCheckBox.Value
                            exc = exc | res.exceptions';
                        end
                        currentThicknesses = res.thicknesses(~exc,1)';
                        nResults = size(currentThicknesses,2);
                        % currentThicknessesL1 = res.thicknesses(~exc,3);
                        % currentThicknessesL2 = res.thicknesses(~exc,4);
                        if size(nResults) > 0
                            currentMean = mean(currentThicknesses);
                            currentSTDE = std(currentThicknesses)/sqrt(nResults);
                            allthicknesses = [allthicknesses, currentThicknesses];
                            % allthicknessesL1 = [allthicknessesL1, currentThicknessesL1];
                            % allthicknessesL1 = [allthicknessesL2, currentThicknessesL2];
                            memR = [memR, res.vesicleCoMradius];
                            memT = [memT, currentMean];
                            memE = [memE, currentSTDE];
                        end
                    end
                    app.stat2.allthicknesses = allthicknesses; 
                    app.stat2.memR = memR;
                    app.stat2.memT = memT;
                    app.stat2.memE = memE;
                    app.FittedEditField.Value = fitsN;
                    app.FittedEditField_2.Value = fitsN;
                    if res.status >= 2
                        profilesN = profilesN+size(res.allprofilesF,1);
                        avg = avg + res.avgProfile;
                    end
                end
                app.SegmentsEditField_2.Value = profilesN;
                xa = [1 : size(avg,2)];
                xa = xa.*app.magnificationApxEditField.Value;
                app.stat2.avg = avg;
                app.stat2.xa = xa;
                plot(app.UIAxes_Avgavg2,xa,avg);
                if fitsN > 0
                    [gfit, bestfit, histo] = fitThicknessDistribution(allthicknesses);
                    plot(app.UIAxes_Hist2_2,histo(1,:)',histo(2,:)','o',bestfit(1,:)',bestfit(2,:)');
                    app.MeanEditField_5.Value = gfit.b1;
                    app.sigmaEditField_5.Value = gfit.c1;
                    errorbar(app.UIAxes_Membranes2,memR,memT,memE,'o');
                end
            end
            %3
            membranesN = size(app.cryoResultsArray3,2);
            if membranesN > 0
                app.NEditField_3.Value = membranesN;
                fitsN =0;
                profilesN = 0;
                allthicknesses = [];
                avg = zeros(1,81);
                memR = [];
                memT = [];
                memE = [];
                for index = 1:membranesN
                    res = app.cryoResultsArray3(1,index);
                    exclude = logical(1 & res.tag);
                    if (res.status >= 3) && (~exclude)
                        fitsN = fitsN+1;
                        exc = analyzeResults(res.fitresults,app.maxConfidenceEditField.Value);
                        if app.neglectprofileswexceptionsCheckBox.Value
                            exc = exc | res.exceptions';
                        end
                        currentThicknesses = res.thicknesses(~exc,1)';
                        nResults = size(currentThicknesses,2);
                        % currentThicknessesL1 = res.thicknesses(~exc,3);
                        % currentThicknessesL2 = res.thicknesses(~exc,4);
                        if size(nResults) > 0
                            currentMean = mean(currentThicknesses);
                            currentSTDE = std(currentThicknesses)/sqrt(nResults);
                            allthicknesses = [allthicknesses, currentThicknesses];
                            % allthicknessesL1 = [allthicknessesL1, currentThicknessesL1];
                            % allthicknessesL1 = [allthicknessesL2, currentThicknessesL2];
                            memR = [memR, res.vesicleCoMradius];
                            memT = [memT, currentMean];
                            memE = [memE, currentSTDE];
                        end
                    end
                    app.stat3.allthicknesses = allthicknesses; 
                    app.stat3.memR = memR;
                    app.stat3.memT = memT;
                    app.stat3.memE = memE;
                    app.FittedEditField_3.Value = fitsN;
                    if res.status >= 2
                        profilesN = profilesN+size(res.allprofilesF,1);
                        avg = avg + res.avgProfile;
                    end
                end
                app.SegmentsEditField_3.Value = profilesN;
                xa = [1 : size(avg,2)];
                xa = xa.*app.magnificationApxEditField.Value;
                app.stat3.avg = avg;
                app.stat3.xa = xa;
                plot(app.UIAxes_Avgavg3,xa,avg);
                if fitsN >0
                    [gfit, bestfit, histo] = fitThicknessDistribution(allthicknesses);
                    plot(app.UIAxes_Hist3_2,histo(1,:)',histo(2,:)','o',bestfit(1,:)',bestfit(2,:)');
                    app.MeanEditField_6.Value = gfit.b1;
                    app.sigmaEditField_6.Value = gfit.c1;
                    errorbar(app.UIAxes_Membranes3,memR,memT,memE,'o');
                end
            end
            app.MembraneSpinner.Value = 0;
            app.MembraneSpinner_2.Value =0;
        end
        
        function cryoResults = batchFit(app,cryoResults)
            alignedProfiles = cryoResults.allprofilesF;
            [fitresults,fitsigmas,fitconfidence,thicknesses] = fitProfiles1p(alignedProfiles,true);
            [fitresults, thicknesses,histo,gfit,bestfit] = fillfitresultsarray(app,fitresults,fitsigmas,fitconfidence,thicknesses);
            %
            cryoResults.status = 3;  %profiles fitted
            cryoResults.histo = histo;
            cryoResults.bestfit = bestfit;
            cryoResults.fitresults = fitresults;
            cryoResults.thicknesses = thicknesses;
            cryoResults.thicknessDistrMean = gfit.b1;
            nFit = sum(~isnan(thicknesses(:,1)));
            if nFit ~=0
                stderr = gfit.b1/sqrt(nFit);
            else
                stderr = gfit.c1;
            end
            cryoResults.thicknessDistrErr = stderr;
            cryoResults.thicknessN = nFit;
        end
        
        function updateGraphs(app)
            image(app.UIAxes_CryoImage_2,app.cryoResults1.vesicleimage,'CDataMapping','scaled');
            app.allProfilesSegments = app.cryoResults1.allprofilesF;
            app.allProfilesSegmentsSmoothed = smoothdata(app.allProfilesSegments,2,"gaussian",5);
            image(app.UIAxes_AllProfiles1,app.allProfilesSegmentsSmoothed,'CDataMapping','scaled');
            image(app.UIAxes_AllProfilesTrans1,app.allProfilesSegmentsSmoothed','CDataMapping','scaled');
            image(app.UIAxes_Exceptions1,app.cryoResults1.exceptions,'CDataMapping','scaled');
            plot(app.UIAxes_AvgProfile1,app.x,app.cryoResults1.avgProfile);
            histo = app.cryoResults1.histo;
            bestfit = app.cryoResults1.bestfit;
            plot(app.UIAxes_Hist1,histo(1,:)',histo(2,:)','o',bestfit(1,:)',bestfit(2,:)');
            avg = app.avglengthEditField.Value;
            dl = 1:size(app.cryoResults1.thicknesses,1);
            thicknessesSmooth = smoothdata(app.cryoResults1.thicknesses(:,1),"movmean",avg);
            plot(app.UIAxes_ThicknessProfile1,dl,app.cryoResults1.thicknesses(:,1),dl,thicknessesSmooth);
            exc = analyzeResults(app.cryoResults1.fitresults,app.maxConfidenceEditField.Value);
            image(app.UIAxes_Confidence1,exc','CDataMapping','scaled');
            app.MeanEditField.Value = app.cryoResults1.thicknessDistrMean;
            app.sigmaEditField.Value = app.cryoResults1.thicknessDistrErr;
            app.CoMradiusAEditField.Value = app.cryoResults1.vesicleCoMradius;
            app.profilestotalEditField.Value = size(app.cryoResults1.allprofilesF,1);
            app.ImageEditField.Value = app.cryoResults1.imagepath;
            app.Tag1excludeCheckBox.Value = logical(1 & app.cryoResults1.tag);
             if app.Tag1excludeCheckBox.Value
                 app.includedLamp.Color = 'red';
             else
                 app.includedLamp.Color = 'green';
             end
            drawnow
            if (app.NEditField_2.Value >0 && app.cryoResults2.status >0)
                image(app.UIAxes_CryoImage_3,app.cryoResults2.vesicleimage,'CDataMapping','scaled');
                app.allProfilesSegments2 = app.cryoResults2.allprofilesF;
                app.allProfilesSegments2Smoothed = smoothdata(app.allProfilesSegments2,2,"gaussian",5);
                image(app.UIAxes_AllProfiles2,app.allProfilesSegments2Smoothed,'CDataMapping','scaled');
                image(app.UIAxes_AllProfilesTrans2,app.allProfilesSegmentsSmoothed','CDataMapping','scaled');
                image(app.UIAxes_Exceptions2,app.cryoResults2.exceptions,'CDataMapping','scaled');
                plot(app.UIAxes_AvgProfile2,app.x,app.cryoResults2.avgProfile);
                histo = app.cryoResults2.histo;
                bestfit = app.cryoResults2.bestfit;
                plot(app.UIAxes_Hist2,histo(1,:)',histo(2,:)','o',bestfit(1,:)',bestfit(2,:)');
                plot(app.UIAxes_ThicknessProfile2,app.cryoResults2.thicknesses(:,1));
                exc = analyzeResults(app.cryoResults2.fitresults,app.maxConfidenceEditField.Value);
                image(app.UIAxes_Confidence2,exc','CDataMapping','scaled');
                app.MeanEditField_2.Value = app.cryoResults2.thicknessDistrMean;
                app.sigmaEditField_2.Value = app.cryoResults2.thicknessDistrErr;
            end
            if (app.NEditField_3.Value >0 && app.cryoResults3.status >0)
                image(app.UIAxes_CryoImage_4,app.cryoResults3.vesicleimage,'CDataMapping','scaled');
                app.allProfilesSegments3 = app.cryoResults3.allprofilesF;
                app.allProfilesSegments3Smoothed = smoothdata(app.allProfilesSegments3,2,"gaussian",5);
                image(app.UIAxes_AllProfiles3,app.allProfilesSegments3Smoothed,'CDataMapping','scaled');
                image(app.UIAxes_AllProfilesTrans3,app.allProfilesSegments3Smoothed','CDataMapping','scaled');
                image(app.UIAxes_Exceptions3,app.cryoResults3.exceptions,'CDataMapping','scaled');
                plot(app.UIAxes_AvgProfile3,app.x,app.cryoResults3.avgProfile);
                histo = app.cryoResults3.histo;
                bestfit = app.cryoResults3.bestfit;
                plot(app.UIAxes_Hist3,histo(1,:)',histo(2,:)','o',bestfit(1,:)',bestfit(2,:)');
                plot(app.UIAxes_ThicknessProfile3,app.cryoResults3.thicknesses(:,1));
                exc = analyzeResults(app.cryoResults3.fitresults,app.maxConfidenceEditField.Value);
                image(app.UIAxes_Confidence3,exc','CDataMapping','scaled');
                app.MeanEditField_3.Value = app.cryoResults3.thicknessDistrMean;
                app.sigmaEditField_3.Value = app.cryoResults3.thicknessDistrErr;
            end
            app.ProfileSpinner.Value =1;
        end
        
        function [resultFileName1, resultFileName2, resultFileName3] = createAnalysisFileNames(app,useprevious)
            if (useprevious && ~isempty(app.analysisFileNames))
                resultFileName1 = char(app.analysisFileNames(1,1));
                resultFileName2 = char(app.analysisFileNames(1,2));
                resultFileName3 = char(app.analysisFileNames(1,3));
            else
                % Creating Folder Name from Timestamp
               
                resultFolder = [char(app.analysisPath.Value) filesep datestr(now,'yyyymmdd_HHMM_') 'Cryo_' char(app.ResultsEditField.Value)];
                mkdir(char(resultFolder));
                resultFileName1 = [resultFolder filesep datestr(now,'yyyymmdd_HHMM_') 'Cryo_' char(app.ResultsEditField.Value) '_1'];
                resultFileName2 = [resultFolder filesep datestr(now,'yyyymmdd_HHMM_') 'Cryo_' char(app.ResultsEditField.Value) '_2'];
                resultFileName3 = [resultFolder filesep datestr(now,'yyyymmdd_HHMM_') 'Cryo_' char(app.ResultsEditField.Value) '_3'];
                app.analysisFileNames = [string(resultFileName1), string(resultFileName2), string(resultFileName3)];
            end
        end
        
        function saveResults(app, usepreviousNames)
            [resultFile1, resultFile2, resultFile3] = createAnalysisFileNames(app,usepreviousNames);
            if size(app.cryoResultsArray1,1) >= 1
                cryoResultArray = app.cryoResultsArray1;
                save(char(resultFile1),'cryoResultArray');
            end
            if size(app.cryoResultsArray2,1) >= 1
                cryoResultArray = app.cryoResultsArray2;
                save(char(resultFile2),'cryoResultArray');
            end
            if size(app.cryoResultsArray3,1) >= 1
                cryoResultArray = app.cryoResultsArray3;
                save(char(resultFile3),'cryoResultArray');
            end
        end
        
        
        function results = refineProfiles(app)
            %
            %profiles = app.cryoResults1.allprofilesF;
            [app.allProfilesSegments,positionsNew] = refineProfiles(app.allProfilesSegments);
            %
            app.allProfilesSegmentsSmoothed = smoothdata(app.allProfilesSegments,2,"gaussian",5);
            image(app.UIAxes_AllProfiles1,app.allProfilesSegmentsSmoothed,'CDataMapping','scaled');
            app.x = [1:81];
            if app.useAngstromCheckBox.Value
                app.x = app.x .*app.magnificationApxEditField.Value;
            end
            avgProfile1 = mean(app.allProfilesSegments,1);
            plot(app.UIAxes_AvgProfile1,app.x,avgProfile1);
            drawnow
            app.cryoResults1.status = 2;  %profiles aligned
            app.cryoResults1.allprofilesF = app.allProfilesSegments;
            app.cryoResults1.avgProfile = avgProfile1;
            app.cryoResultsArray1(1,app.MembraneSpinner.Value) = app.cryoResults1;
            if app.group2
                app.allProfilesSegments2 = shiftProfiles(app.allProfilesSegments2,positionsNew);
                app.allProfilesSegments2Smoothed = smoothdata(app.allProfilesSegments,2,"gaussian",5);
                image(app.UIAxes_AllProfiles2,app.allProfilesSegments2Smoothed,'CDataMapping','scaled');
                avgProfile2 = mean(app.allProfilesSegments2,1);
                plot(app.UIAxes_AvgProfile2,app.x,avgProfile2);
                drawnow
                app.cryoResults2.status = 2;  %profiles aligned
                app.cryoResults2.allprofilesF = app.allProfilesSegments2;
                app.cryoResults2.avgProfile = avgProfile2;
                app.cryoResultsArray2(1,app.MembraneSpinner.Value) = app.cryoResults2;
            end
            if app.group3
                app.allProfilesSegments3 = shiftProfiles(app.allProfilesSegments3,positionsNew);
                app.allProfilesSegments3Smoothed = smoothdata(app.allProfilesSegments3,2,"gaussian",5);
                image(app.UIAxes_AllProfiles3,app.allProfilesSegments3Smoothed,'CDataMapping','scaled');
                avgProfile3 = mean(app.allProfilesSegments3,1);
                plot(app.UIAxes_AvgProfile3,app.x,avgProfile3);
                drawnow
                app.cryoResults3.status = 2;  %profiles aligned
                app.cryoResults3.allprofilesF = app.allProfilesSegments3;
                app.cryoResults3.avgProfile = avgProfile3;
                app.cryoResultsArray3(1,app.MembraneSpinner.Value) = app.cryoResults3;
            end
        
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.actualPath.Value = '';
            fillFileList(app,char(app.actualPath.Value));
            app.analysisFileList = [];
            app.roiCArray = [];
            app.extraImages = [];
            app.previousROIs = [];
            app.cryoResults1 = cryoResultInit(0);
            app.cryoResults2 = cryoResultInit(0);
            app.cryoResults3 = cryoResultInit(0);
            app.analysisPath.Value ='';
            app.analysisPath.Value = userpath;
            app.mxCombinePath = '';
            colormap(app.UIAxes_CryoImage,gray);
            colormap(app.UIAxes_CryoImage_2,gray);
            colormap(app.UIAxes_CryoImage_3,gray);
            colormap(app.UIAxes_CryoImage_4,gray);
            colormap(app.UIAxes_AllProfiles1,jet);
            colormap(app.UIAxes_AllProfiles2,jet);
            colormap(app.UIAxes_AllProfiles3,jet);
            app.ROIButtonAn.Enable = false;
            app.ProfileSpinner.Enable = false;
            app.extractradialprofilesButton.Enable = false;
            app.alignButton.Enable = false;
            app.fitButton.Enable = false;
            app.extractandalignButton.Enable = false;
            app.autosaveCheckBox.Enable = false;
            app.x = [1:81];
            app.refineButton.Enable = false;
            app.group1 = false;
            app.Group1Lamp.Color = 'red';
            app.group2 = false;
            app.Group2Lamp.Color = 'red';
            app.group3 = false;
            app.Group3Lamp.Color = 'red';
        end

        % Button pushed function: ChangeFolderButton
        function ChangeFolderButtonPushed(app, event)
            selpath = uigetdir(char(app.actualPath.Value));
            if selpath ~= 0
                app.actualPath.Value = selpath;
                fillFileList(app,selpath);
            end
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)

            selpath = char(app.actualPath.Value);
            fillFileList(app,selpath);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {945, 945};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {330, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: AnalysisFolderButton
        function AnalysisFolderButtonPushed(app, event)
            selpath = uigetdir(char(app.analysisPath.Value));
            app.analysisPath.Value = selpath;
        end

        % Button pushed function: OrganizeforMathematicaButton
        function OrganizeforMathematicaButtonPushed(app, event)
            numberOrganize = size(app.analysisFileList,2);
            selpath = char(app.actualPath.Value);
            analysispath = char(app.analysisPath.Value);
            addonString = string(app.addonEditField.Value);
            mxCombineNew = char([selpath filesep 'mxCombine-v2.2-' addonString '.nb']);
            copyfile(app.mxCombinePath, mxCombineNew);
            for index = 1:numberOrganize
                filename = char(app.analysisFileList(1,index));
                filepath = [selpath filesep filename];
                createMathematicaFolder(filepath,analysispath,index,app.InvertCheckBox.Value);
            end
            app.extraImages = [];
            app.SelectadditionalimagesListBox.Items = [];
        end

        % Button pushed function: ImageLoadButton
        function ImageLoadButtonPushed(app, event)
            value = app.cryoListBox.Value;
            filename = char(app.cryoListBox.Items(value));
            selpath = char(app.actualPath.Value);
            filepath = char([selpath filesep filename]);
            [~,~,ext] = fileparts(filepath);
            switch ext
                case '.mrc'
                    [m, ~]=ReadMRC(filepath,1,1,0);
                    if app.InvertCheckBox_2.Value
                        m=-m;
                    end
                case '.tiff'
                    imageData = bfopen(filepath);
                    m = imageData{1,1};
                    
            end
            app.imageEditField.Value = char(filename);
            colormap(app.UIAxes_CryoImage,gray);
            image(app.UIAxes_CryoImage,m,'CDataMapping','scaled');
            app.cryoResults1 = cryoresultsInit(1,filepath); %Create new cryoresult structure template
            app.cryoImage = m;
            app.previousROIs = [];
            app.ROIButtonAn.Enable = true;
        end

        % Value changed function: cryoListBox
        function cryoListBoxValueChanged(app, event)
            value = app.cryoListBox.Value;
            numberSelected = size(value,2);
            app.analysisFileList = app.cryoListBox.Items(value);
%             for index = 1 : numberSelected
%                 app.analysisFileList(index) = app.cryoListBox.Items(value);
%             end
        end

        % Button pushed function: ROIButtonAn
        function ROIButtonAnPushed(app, event)
             %draw a freehand ROI
            delete(app.roiC);
            app.radiusEditField.Value = 0;
            app.xCEditField.Value = 0;
            app.yCEditField.Value = 0;
            if app.externalfigureCheckBox.Value
%                 figure;
            app.cryoImageExt = image(app.cryoImage,"CDataMapping",'scaled');
            colormap gray;
            if size(app.previousROIs,1) >= 1
                for index = 1:size(app.previousROIs,1)
                     drawcircle('Center',app.previousROIs(index,1:2),'Radius',app.previousROIs(index,3),'StripeColor','red');
                end
            end
            roi=drawcircle;
            wait(roi);
            else
               image(app.UIAxes_CryoImage,app.cryoImage,'CDataMapping','scaled');

                switch app.TypeDropDown.Value
                    case 'freehand'
                        roi = drawfreehand(app.UIAxes_CryoImage);
                        wait(roi);
                    case 'drawassisted'
                        roi = drawassisted(app.UIAxes_CryoImage);
                        wait(roi);
                    case 'ellipse'
                        roi = drawellipse(app.UIAxes_CryoImage);
                        wait(roi);
                    case 'circle'
                        roi = drawcircle(app.UIAxes_CryoImage);
                        wait(roi);
                    case 'line'
                        roi = drawline(app.UIAxes_CryoImage);
                        wait(roi);
                    case 'polyline'
                        roi = drawpolyline(app.UIAxes_CryoImage);
                        wait(roi);
                    case 'polygon'
                        roi = drawpolygon(app.UIAxes_CryoImage);
                        wait(roi);
                end 
            end
            
            app.radiusEditField.Value = roi.Radius;
            app.xCEditField.Value = roi.Center(1,1);
            app.yCEditField.Value = roi.Center(1,2);
            app.roiC = roi;
            app.roiCArray = [app.roiCArray, roi];
            app.extractradialprofilesButton.Enable = true;
            app.extractandalignButton.Enable = true;
            app.refineButton.Enable = false;
        end

        % Button pushed function: deleteROIButton
        function deleteROIButtonPushed(app, event)
            delete(app.roiC);
            app.roiCArray = app.roiCArray(1,1:end-1);
        end

        % Button pushed function: extractradialprofilesButton
        function extractradialprofilesButtonPushed(app, event)
            extractProfiles(app);
            app.alignButton.Enable = true;
            app.fitButton.Enable = false;
        end

        % Value changed function: ProfileSpinner
        function ProfileSpinnerValueChanged(app, event)
            value = app.ProfileSpinner.Value;
            res = app.cryoResults1;
            profileSelect = res.allprofilesF(value,:);
            profileSelectSmooth = smoothdata(profileSelect,2,'gaussian',5);
            if res.status >= 3
                fitx = [round(res.fitresults(value,1)), round(res.fitresults(value,3))];
                fity = [profileSelect(1,fitx(1,1)), profileSelect(1,fitx(1,2))];
                plot(app.UIAxes,app.x,profileSelect,'b',app.x,profileSelectSmooth,'g',fitx,fity,'o','MarkerSize',10,...
                'MarkerEdgeColor','r','MarkerFaceColor','r');
                app.dEditField.Value = res.thicknesses(value,1);
            else
                 plot(app.UIAxes,app.x,profileSelect,'b',app.x,profileSelectSmooth,'r');
            end
            h=image(app.cryoImage,"CDataMapping",'scaled');
            if app.showlinesegmentsCheckBox.Value
                circleCenter = [res.vesicleposition(1,1), res.vesicleposition(1,2)];
                circleRadius = res.vesicleposition(1,3);
                segmentLengthPx = app.segmentpxEditField.Value;
                profilepx = app.profilepxEditField.Value;
                [lineXY, ~] = calcRadialLineSegment(value,circleCenter,circleRadius,segmentLengthPx,profilepx);
                drawline('Position',lineXY(1:2,:));
                drawline(app.UIAxes_CryoImage_2,'Position',lineXY(3:4,:));
            end
            if (app.group2 && app.cryoResults2.status > 0)
                res = app.cryoResults2;
                profileSelect2 = res.allprofilesF(value,:);
                profileSelect2Smooth = smoothdata(profileSelect,2,'gaussian',5);
                if res.status >= 3
                    fitx = [round(res.fitresults(value,1)), round(res.fitresults(value,3))];
                    fity = [profileSelect2(1,fitx(1,1)), profileSelect2(1,fitx(1,2))];
                    plot(app.UIAxes_2,app.x,profileSelect2,'b',app.x,profileSelect2Smooth,'g',fitx,fity,'o','MarkerSize',10,...
                    'MarkerEdgeColor','r','MarkerFaceColor','r');
                    app.dEditField_2.Value = res.thicknesses(value,1);
                else
                     plot(app.UIAxes_2,app.x,profileSelect2,'b',app.x,profileSelect2Smooth,'r');
                end
            end

            if (app.group3 && app.cryoResults3.status > 0)
                res = app.cryoResults3;
                profileSelect3 = res.allprofilesF(value,:);
                profileSelect3Smooth = smoothdata(profileSelect,2,'gaussian',5);
                if res.status >= 3
                    fitx = [round(res.fitresults(value,1)), round(res.fitresults(value,3))];
                    fity = [profileSelect3(1,fitx(1,1)), profileSelect3(1,fitx(1,2))];
                    plot(app.UIAxes_3,app.x,profileSelect3,'b',app.x,profileSelect3Smooth,'g',fitx,fity,'o','MarkerSize',10,...
                    'MarkerEdgeColor','r','MarkerFaceColor','r');
                    app.dEditField_3.Value = res.thicknesses(value,1);
                else
                     plot(app.UIAxes_3,app.x,profileSelect3,'b',app.x,profileSelect3Smooth,'r');
                end
            end
            
        end

        % Button pushed function: ROIupdate
        function ROIupdatePushed(app, event)
            app.radiusEditField.Value = roi.Radius;
            app.xCEditField.Value = roi.Center(1,1);
            app.yCEditField.Value = roi.Center(1,2);
        end

        % Value changed function: segmentlengthAEditField
        function segmentlengthAEditFieldValueChanged(app, event)
            value = app.segmentlengthAEditField.Value;
            if app.magnificationApxEditField.Value ~=0
                app.segmentpxEditField.Value = round(value/app.magnificationApxEditField.Value);
            end
        end

        % Value changed function: profilelengthAEditField
        function profilelengthAEditFieldValueChanged(app, event)
            value = app.profilelengthAEditField.Value;
            if app.magnificationApxEditField.Value ~=0
                app.profilepxEditField.Value = round(value/app.magnificationApxEditField.Value);
            end
        end

        % Button pushed function: SecondaryImageButton
        function SecondaryImageButtonPushed(app, event)
            value = app.cryoListBox.Value;
            filename = char(app.cryoListBox.Items(value));
            selpath = char(app.actualPath.Value);
            filepath = char([selpath filesep filename]);
            [~,~,ext] = fileparts(filepath);
            switch ext
                case '.mrc'
                    [m, ~]=ReadMRC(filepath,1,1,0);
                    if app.InvertCheckBox_3.Value
                        m=-m;
                    end
                case '.tiff'
                    imageData = bfopen(filepath);
                    m = imageData{1,1};
            end
            if isempty(app.extraImages)
                app.extraImages = [app.extraImages string(filename)];
                app.cryoImage2 = m;
                app.cryoResults2 = cryoresultsInit(1,filepath);%Create new cryoresult structure template
            elseif size(app.extraImages,2)==1
                app.extraImages = [app.extraImages string(filename)];
                app.cryoImage3 = m;
                app.cryoResults3 = cryoresultsInit(1,filepath);%Create new cryoresult structure template
            elseif size(app.extraImages,2)==2
                app.extraImages(1,1)=string(filename);
                app.cryoImage2 = m; 
                app.cryoResults2 = cryoresultsInit(1,filepath);%Create new cryoresult structure template
            end
            app.SelectadditionalimagesListBox.Items = app.extraImages;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.extraImages = [];
            app.SelectadditionalimagesListBox.Items = "";
        end

        % Button pushed function: alignButton
        function alignButtonPushed(app, event)
            aligneProfiles(app);
            app.alignButton.Enable = false;
            app.fitButton.Enable = true;
            app.refineButton.Enable = true;
        end

        % Button pushed function: fitButton
        function fitButtonPushed(app, event)
            fitProfiles(app);

        end

        % Button pushed function: extractandalignButton
        function extractandalignButtonPushed(app, event)
            extractProfiles(app);
            aligneProfiles(app);
            app.alignButton.Enable = false;
            app.fitButton.Enable = true;
            app.refineButton.Enable = true;
        end

        % Button pushed function: addtostatButton
        function addtostatButtonPushed(app, event)
            app.cryoResultsArray1 = [app.cryoResultsArray1, app.cryoResults1];
            if app.group2
                app.cryoResultsArray2 = [app.cryoResultsArray2, app.cryoResults2];
            end
            if app.group3
                app.cryoResultsArray3 = [app.cryoResultsArray3, app.cryoResults3];
            end
            updateStat(app);
            app.MembraneSpinner.Value = app.NEditField.Value;
            roiData = [app.xCEditField.Value, app.yCEditField.Value, app.radiusEditField.Value];
            app.previousROIs = [app.previousROIs;roiData];
            if app.autosaveCheckBox.Value
                saveResults(app,true);
            end
        end

        % Button pushed function: batchfitButton
        function batchfitButtonPushed(app, event)
            updateStat(app);
            membranesN = app.NEditField.Value;
            if ((membranesN-app.FittedEditField.Value)>0) || app.RefitallCheckBox.Value
                for index = 1:membranesN
                    res = app.cryoResultsArray1(1,index);
                    if (res.status == 2) || ((res.status >= 3) && app.RefitallCheckBox.Value)
                        app.StatusEditField.Value = strcat("Batch fitting group 1 => ",string(index));
                        res = batchFit(app,res);
                        app.cryoResultsArray1(1,index)=res;
                    end
                end
                app.StatusEditField.Value = 'Done with group 1';
            end
            cryoResultArray = app.cryoResultsArray1;
            save("cryoResultsTEMP1.mat","cryoResultArray");
            updateStat(app);
                %
            membranesN = app.NEditField_2.Value;
            if ((membranesN-app.FittedEditField_2.Value)>0) || app.RefitallCheckBox.Value
                for index = 1:membranesN
                    res = app.cryoResultsArray2(1,index);
                    if (res.status == 2) || ((res.status >= 3) && app.RefitallCheckBox.Value)
                        app.StatusEditField.Value = strcat("Batch fitting group 2 => ",string(index));
                        res = batchFit(app,res);
                        app.cryoResultsArray2(1,index)=res;
                    end
                end
                app.StatusEditField.Value = 'Done with group 2';
            else
                app.StatusEditField.Value = 'Nothing to fit in group2';
            end
            cryoResultArray = app.cryoResultsArray2;
            save("cryoResultsTEMP2.mat","cryoResultArray");
            updateStat(app);
                %
            membranesN = app.NEditField_3.Value;
            if ((membranesN-app.FittedEditField_3.Value)>0) || app.RefitallCheckBox.Value
                for index = 1:membranesN
                    res = app.cryoResultsArray3(1,index);
                    if (res.status == 2) || ((res.status >= 3) && app.RefitallCheckBox.Value)
                        app.StatusEditField.Value = strcat("Batch fitting group 3 => ",string(index));
                        res = batchFit(app,res);
                        app.cryoResultsArray3(1,index)=res;
                    end
                end
                app.StatusEditField.Value = 'Done with group 3';
            else
                app.StatusEditField.Value = 'Nothing to fit in group3';
            end
            cryoResultArray = app.cryoResultsArray3;
            save("cryoResultsTEMP3.mat","cryoResultArray");
            updateStat(app);
       

        end

        % Value changed function: FileTypeDropDown
        function FileTypeDropDownValueChanged(app, event)
            value = app.FileTypeDropDown.Value;
            switch value
                case 'MAT'
                    app.ImageLoadButton.Enable = false;
                    app.SecondaryImageButton.Enable = false;
                    app.Stat1LoadButton.Enable = true;
                    app.Stat2LoadButton.Enable = true;
                    app.Stat3LoadButton.Enable = true;
                case {'MRC','TIFF'}
                    app.ImageLoadButton.Enable = true;
                    app.SecondaryImageButton.Enable = true;
                    app.Stat1LoadButton.Enable = false;
                    app.Stat2LoadButton.Enable = false;
                    app.Stat3LoadButton.Enable = false;
            end
            selpath = char(app.actualPath.Value);
            fillFileList(app,selpath);
        end

        % Button pushed function: Stat1LoadButton
        function Stat1LoadButtonPushed(app, event)
            value = app.cryoListBox.Value;
            filename = char(app.cryoListBox.Items(value));
            selpath = char(app.actualPath.Value);
            filepath = char([selpath filesep filename]);         
            app.Group1EditField.Value = char(filename);
            load(filepath,'cryoResultArray');
            if ~isempty(cryoResultArray)
                if app.poolresultsCheckBox.Value
                    app.cryoResultsArray1 = [app.cryoResultsArray1 cryoResultArray];
                else
                    app.cryoResultsArray1 = cryoResultArray;
                end
                updateStat(app);
                app.group1 = true;
                app.Group1Lamp.Color = 'green';
            end
        end

        % Button pushed function: Stat2LoadButton
        function Stat2LoadButtonPushed(app, event)
            value = app.cryoListBox.Value;
            filename = char(app.cryoListBox.Items(value));
            selpath = char(app.actualPath.Value);
            filepath = char([selpath filesep filename]);         
            app.Group2EditField.Value = char(filename);
            load(filepath,'cryoResultArray');
            if ~isempty(cryoResultArray)
                if app.poolresultsCheckBox.Value
                    app.cryoResultsArray2 = [app.cryoResultsArray2 cryoResultArray];
                else
                    app.cryoResultsArray2 = cryoResultArray;
                end
                updateStat(app);
                app.group2 = true;
                app.Group2Lamp.Color = 'green';
            end
        end

        % Button pushed function: Stat3LoadButton
        function Stat3LoadButtonPushed(app, event)
            value = app.cryoListBox.Value;
            filename = char(app.cryoListBox.Items(value));
            selpath = char(app.actualPath.Value);
            filepath = char([selpath filesep filename]);         
            app.Group3EditField.Value = char(filename);
            load(filepath,'cryoResultArray');
            if ~isempty(cryoResultArray)
                if app.poolresultsCheckBox.Value
                    app.cryoResultsArray3 = [app.cryoResultsArray3 cryoResultArray];
                else
                    app.cryoResultsArray3 = cryoResultArray;
                end
                updateStat(app);
                app.group3 = true;
                app.Group3Lamp.Color = 'green';
            end
        end

        % Value changed function: MembraneSpinner, MembraneSpinner_2
        function MembraneSpinnerValueChanged(app, event)
            switch event.Source
                case app.MembraneSpinner
                    value = app.MembraneSpinner.Value;
                    app.MembraneSpinner_2.Value = value;
                case app.MembraneSpinner_2
                    value = app.MembraneSpinner_2.Value;
                    app.MembraneSpinner.Value = value;
            end

            if value <= app.NEditField.Value
                app.cryoResults1 = app.cryoResultsArray1(1,value);
                app.ProfileSpinner.Enable = true;
                app.ProfileSpinner.Value = 1;
                app.refineButton.Enable = true;
                app.alignButton.Enable = true;
                app.fitButton.Enable = true;
            end
            if value <= app.NEditField_2.Value
                app.cryoResults2 = app.cryoResultsArray2(1,value);
            end
            if value <= app.NEditField_3.Value
                app.cryoResults3 = app.cryoResultsArray3(1,value);
            end
            updateGraphs(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveResults(app,false);
            app.autosaveCheckBox.Enable = true;
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            app.cryoResultsArray1 = [];
            app.cryoResultsArray2 = [];
            app.cryoResultsArray3 = [];
            app.NEditField.Value = 0;
            app.NEditField_4.Value = 0;
            app.NEditField_2.Value = 0;
            app.NEditField_3.Value = 0;
            app.group1 = false;
            app.Group1Lamp.Color = 'red';
            app.group2 = false;
            app.Group2Lamp.Color = 'red';
            app.group3 = false;
            app.Group3Lamp.Color = 'red';
            app.refineButton.Enable = false;
            app.alignButton.Enable = false;
            app.fitButton.Enable = false;
        end

        % Value changed function: useAngstromCheckBox
        function useAngstromCheckBoxValueChanged(app, event)
            value = app.useAngstromCheckBox.Value;
            app.x = [1:81];
            if value
                app.x = app.x*app.magnificationApxEditField.Value;
            end
            
        end

        % Button pushed function: SaveButton_stat1, SaveButton_stat2, 
        % ...and 1 other component
        function SaveButton_stat1Pushed(app, event)
            switch event.Source
                case app.SaveButton_stat1
                    stat = app.stat1;
                case app.SaveButton_stat2
                    stat = app.stat2;
                case app.SaveButton_stat3
                    stat = app.stat3;
            end
            uisave('stat');
        end

        % Button pushed function: UpdatewithnewMagnificationButton
        function UpdatewithnewMagnificationButtonPushed(app, event)
            if app.NEditField.Value >= 0
                app.cryoResultsArray1 = changeMagnification(app.cryoResultsArray1,app.newmagnificationApxEditField.Value);   
            end
            if app.NEditField_2.Value >= 0
                app.cryoResultsArray2 = changeMagnification(app.cryoResultsArray2,app.newmagnificationApxEditField.Value);   
            end
            if app.NEditField_3.Value >= 0
                app.cryoResultsArray3 = changeMagnification(app.cryoResultsArray3,app.newmagnificationApxEditField.Value);   
            end
            updateStat(app);
            app.magnificationApxEditField.Value = app.newmagnificationApxEditField.Value;
        end

        % Button pushed function: updateButton
        function updateButtonPushed(app, event)
            updateStat(app);
        end

        % Value changed function: Tag1excludeCheckBox
        function Tag1excludeCheckBoxValueChanged(app, event)
            value = app.Tag1excludeCheckBox.Value;
            newTag1 = uint16(value | app.cryoResults1.tag);
            app.cryoResults1.tag = newTag1;
            if value
                 app.includedLamp.Color = 'red';
             else
                 app.includedLamp.Color = 'green';
             end
            membrane = app.MembraneSpinner.Value;
            app.cryoResultsArray1(1,membrane) = app.cryoResults1;
            if app.NEditField_2.Value >= 0
                app.cryoResultsArray2(1,membrane).tag = newTag1;   
            end
            if app.NEditField_3.Value >= 0
                app.cryoResultsArray3(1,membrane).tag = newTag1;     
            end
            updateStat(app);
        end

        % Button pushed function: refineButton
        function refineButtonPushed(app, event)
            refineProfiles(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.FolderTab = uitab(app.TabGroup2);
            app.FolderTab.Title = 'Folder';
            app.ReloadButton = uibutton(app.FolderTab, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.ChangeFolderButton = uibutton(app.FolderTab, 'push');
            app.ChangeFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeFolderButtonPushed, true);
            app.ChangeFolderButton.Text = 'Change Folder';
            app.actualPath = uitextarea(app.FolderTab);
            app.FileTypeDropDownLabel = uilabel(app.FolderTab);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.FileTypeDropDown = uidropdown(app.FolderTab);
            app.FileTypeDropDown.Items = {'MRC', 'TIFF', 'MAT'};
            app.FileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDownValueChanged, true);
            app.FileTypeDropDown.Value = 'MRC';
            app.OrganizeTab = uitab(app.TabGroup2);
            app.OrganizeTab.Title = 'Organize';
            app.analysisPath = uitextarea(app.OrganizeTab);
            app.AnalysisFolderButton = uibutton(app.OrganizeTab, 'push');
            app.AnalysisFolderButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisFolderButtonPushed, true);
            app.AnalysisFolderButton.Text = 'Analysis Folder';
            app.OrganizeforMathematicaButton = uibutton(app.OrganizeTab, 'push');
            app.OrganizeforMathematicaButton.ButtonPushedFcn = createCallbackFcn(app, @OrganizeforMathematicaButtonPushed, true);
            app.OrganizeforMathematicaButton.Text = 'Organize for Mathematica';
            app.InvertCheckBox = uicheckbox(app.OrganizeTab);
            app.InvertCheckBox.Text = 'Invert';
            app.addonEditFieldLabel = uilabel(app.OrganizeTab);
            app.addonEditFieldLabel.Text = 'add on';
            app.addonEditField = uieditfield(app.OrganizeTab, 'text');
            app.PresetsTab = uitab(app.TabGroup2);
            app.PresetsTab.Title = 'Presets';
            app.LoadPresetsButton = uibutton(app.PresetsTab, 'push');
            app.LoadPresetsButton.Text = 'Load';
            app.SavePresetsButton = uibutton(app.PresetsTab, 'push');
            app.SavePresetsButton.Text = 'Save';
            app.cryoListBox = uilistbox(app.LeftPanel);
            app.cryoListBox.Items = {};
            app.cryoListBox.Multiselect = 'on';
            app.cryoListBox.ValueChangedFcn = createCallbackFcn(app, @cryoListBoxValueChanged, true);
            app.cryoListBox.Value = {};
            app.FoldersLabel = uilabel(app.LeftPanel);
            app.FoldersLabel.Text = 'Folders';
            app.SelectadditionalimagesListBoxLabel = uilabel(app.LeftPanel);
            app.SelectadditionalimagesListBoxLabel.Text = 'Select additional images';
            app.SelectadditionalimagesListBox = uilistbox(app.LeftPanel);
            app.SelectadditionalimagesListBox.Items = {};
            app.SelectadditionalimagesListBox.Value = {};
            app.SecondaryImageButton = uibutton(app.LeftPanel, 'push');
            app.SecondaryImageButton.ButtonPushedFcn = createCallbackFcn(app, @SecondaryImageButtonPushed, true);
            app.SecondaryImageButton.Text = '>';
            app.InvertCheckBox_3 = uicheckbox(app.LeftPanel);
            app.InvertCheckBox_3.Text = 'Invert';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.useAngstromCheckBox = uicheckbox(app.LeftPanel);
            app.useAngstromCheckBox.ValueChangedFcn = createCallbackFcn(app, @useAngstromCheckBoxValueChanged, true);
            app.useAngstromCheckBox.Text = 'use Angstrom';
            app.magnificationApxEditFieldLabel = uilabel(app.LeftPanel);
            app.magnificationApxEditFieldLabel.Text = 'magnification (A/px)';
            app.magnificationApxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.magnificationApxEditField.Limits = [0.1 Inf];
            app.magnificationApxEditField.Value = 1.9;
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.imageTab = uitab(app.TabGroup3);
            app.imageTab.Title = 'image';
            app.UIAxes_CryoImage = uiaxes(app.imageTab);
            title(app.UIAxes_CryoImage, 'Cryo Image')
            xlabel(app.UIAxes_CryoImage, 'X')
            ylabel(app.UIAxes_CryoImage, 'Y')
            app.ImageLoadButton = uibutton(app.imageTab, 'push');
            app.ImageLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ImageLoadButtonPushed, true);
            app.ImageLoadButton.Text = '>';
            app.ROIButtonAn = uibutton(app.imageTab, 'push');
            app.ROIButtonAn.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonAnPushed, true);
            app.ROIButtonAn.Text = 'new ROI';
            app.TypeDropDownLabel = uilabel(app.imageTab);
            app.TypeDropDownLabel.Enable = 'off';
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.imageTab);
            app.TypeDropDown.Items = {'freehand', 'circle', 'assist', 'line', 'polyline', 'polygon'};
            app.TypeDropDown.Enable = 'off';
            app.TypeDropDown.Value = 'circle';
            app.deleteROIButton = uibutton(app.imageTab, 'push');
            app.deleteROIButton.ButtonPushedFcn = createCallbackFcn(app, @deleteROIButtonPushed, true);
            app.deleteROIButton.Text = 'delete ROI';
            app.radiusEditFieldLabel = uilabel(app.imageTab);
            app.radiusEditFieldLabel.Text = 'radius';
            app.radiusEditField = uieditfield(app.imageTab, 'numeric');
            app.xCEditFieldLabel = uilabel(app.imageTab);
            app.xCEditFieldLabel.Text = 'xC';
            app.xCEditField = uieditfield(app.imageTab, 'numeric');
            app.yCEditFieldLabel = uilabel(app.imageTab);
            app.yCEditFieldLabel.Text = 'yC';
            app.yCEditField = uieditfield(app.imageTab, 'numeric');
            app.ROIupdate = uibutton(app.imageTab, 'push');
            app.ROIupdate.ButtonPushedFcn = createCallbackFcn(app, @ROIupdatePushed, true);
            app.ROIupdate.Text = 'update';
            app.externalfigureCheckBox = uicheckbox(app.imageTab);
            app.externalfigureCheckBox.Text = 'external figure';
            app.externalfigureCheckBox.Value = true;
            app.InvertCheckBox_2 = uicheckbox(app.imageTab);
            app.InvertCheckBox_2.Text = 'Invert';
            app.imageEditFieldLabel = uilabel(app.imageTab);
            app.imageEditFieldLabel.Text = 'image';
            app.imageEditField = uieditfield(app.imageTab, 'text');
            app.profilesTab = uitab(app.TabGroup3);
            app.profilesTab.Title = 'profiles';
            app.UIAxes = uiaxes(app.profilesTab);
            title(app.UIAxes, 'Profile 1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'I')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_CryoImage_2 = uiaxes(app.profilesTab);
            title(app.UIAxes_CryoImage_2, 'Cryo Image')
            xlabel(app.UIAxes_CryoImage_2, 'X')
            ylabel(app.UIAxes_CryoImage_2, 'Y')
            app.UIAxes_CryoImage_3 = uiaxes(app.profilesTab);
            title(app.UIAxes_CryoImage_3, 'Cryo Image')
            xlabel(app.UIAxes_CryoImage_3, 'X')
            ylabel(app.UIAxes_CryoImage_3, 'Y')
            app.UIAxes_3 = uiaxes(app.profilesTab);
            title(app.UIAxes_3, 'Profile 3')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'I')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_CryoImage_4 = uiaxes(app.profilesTab);
            title(app.UIAxes_CryoImage_4, 'Cryo Image')
            xlabel(app.UIAxes_CryoImage_4, 'X')
            ylabel(app.UIAxes_CryoImage_4, 'Y')
            app.UIAxes_2 = uiaxes(app.profilesTab);
            title(app.UIAxes_2, 'Profile 2')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'I')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_AllProfiles1 = uiaxes(app.profilesTab);
            title(app.UIAxes_AllProfiles1, 'All Profiles 1')
            xlabel(app.UIAxes_AllProfiles1, 'X')
            ylabel(app.UIAxes_AllProfiles1, '#')
            app.UIAxes_AllProfiles2 = uiaxes(app.profilesTab);
            title(app.UIAxes_AllProfiles2, 'All Profiles 2')
            xlabel(app.UIAxes_AllProfiles2, 'X')
            ylabel(app.UIAxes_AllProfiles2, '#')
            app.UIAxes_AllProfiles3 = uiaxes(app.profilesTab);
            title(app.UIAxes_AllProfiles3, 'All Profiles 3')
            xlabel(app.UIAxes_AllProfiles3, 'X')
            ylabel(app.UIAxes_AllProfiles3, '#')
            app.UIAxes_AvgProfile1 = uiaxes(app.profilesTab);
            title(app.UIAxes_AvgProfile1, 'Avg Profile 1')
            xlabel(app.UIAxes_AvgProfile1, 'X')
            ylabel(app.UIAxes_AvgProfile1, 'I')
            zlabel(app.UIAxes_AvgProfile1, 'Z')
            app.UIAxes_AvgProfile2 = uiaxes(app.profilesTab);
            title(app.UIAxes_AvgProfile2, 'Avg Profile 2')
            xlabel(app.UIAxes_AvgProfile2, 'X')
            ylabel(app.UIAxes_AvgProfile2, 'I')
            zlabel(app.UIAxes_AvgProfile2, 'Z')
            app.UIAxes_AvgProfile3 = uiaxes(app.profilesTab);
            title(app.UIAxes_AvgProfile3, 'Avg Profile 3')
            xlabel(app.UIAxes_AvgProfile3, 'X')
            ylabel(app.UIAxes_AvgProfile3, 'I')
            zlabel(app.UIAxes_AvgProfile3, 'Z')
            app.UIAxes_Hist1 = uiaxes(app.profilesTab);
            title(app.UIAxes_Hist1, 'Thicknesses 1')
            xlabel(app.UIAxes_Hist1, 'd')
            ylabel(app.UIAxes_Hist1, '#')
            zlabel(app.UIAxes_Hist1, 'Z')
            app.UIAxes_Hist2 = uiaxes(app.profilesTab);
            title(app.UIAxes_Hist2, 'Thicknesses 2')
            xlabel(app.UIAxes_Hist2, 'd')
            ylabel(app.UIAxes_Hist2, '#')
            zlabel(app.UIAxes_Hist2, 'Z')
            app.UIAxes_Hist3 = uiaxes(app.profilesTab);
            title(app.UIAxes_Hist3, 'Thicknesses 3')
            xlabel(app.UIAxes_Hist3, 'd')
            ylabel(app.UIAxes_Hist3, '#')
            zlabel(app.UIAxes_Hist3, 'Z')
            app.ProfileLabel = uilabel(app.profilesTab);
            app.ProfileLabel.Text = 'Profile #';
            app.ProfileSpinner = uispinner(app.profilesTab);
            app.ProfileSpinner.ValueChangedFcn = createCallbackFcn(app, @ProfileSpinnerValueChanged, true);
            app.extractradialprofilesButton = uibutton(app.profilesTab, 'push');
            app.extractradialprofilesButton.ButtonPushedFcn = createCallbackFcn(app, @extractradialprofilesButtonPushed, true);
            app.extractradialprofilesButton.Text = 'extract radial profiles';
            app.segmentlengthAEditFieldLabel = uilabel(app.profilesTab);
            app.segmentlengthAEditFieldLabel.Text = 'segment length (A)';
            app.segmentlengthAEditField = uieditfield(app.profilesTab, 'numeric');
            app.segmentlengthAEditField.ValueChangedFcn = createCallbackFcn(app, @segmentlengthAEditFieldValueChanged, true);
            app.segmentlengthAEditField.Value = 50;
            app.segmentpxEditFieldLabel = uilabel(app.profilesTab);
            app.segmentpxEditFieldLabel.Text = 'segment px';
            app.segmentpxEditField = uieditfield(app.profilesTab, 'numeric');
            app.segmentpxEditField.Value = 31;
            app.profilelengthAEditFieldLabel = uilabel(app.profilesTab);
            app.profilelengthAEditFieldLabel.Text = 'profile length (A)';
            app.profilelengthAEditField = uieditfield(app.profilesTab, 'numeric');
            app.profilelengthAEditField.Limits = [50 Inf];
            app.profilelengthAEditField.ValueChangedFcn = createCallbackFcn(app, @profilelengthAEditFieldValueChanged, true);
            app.profilelengthAEditField.Value = 160;
            app.profilepxEditFieldLabel = uilabel(app.profilesTab);
            app.profilepxEditFieldLabel.Text = 'profile px';
            app.profilepxEditField = uieditfield(app.profilesTab, 'numeric');
            app.profilepxEditField.Value = 93;
            app.profilestotalEditFieldLabel = uilabel(app.profilesTab);
            app.profilestotalEditFieldLabel.Text = 'profiles total';
            app.profilestotalEditField = uieditfield(app.profilesTab, 'numeric');
            app.alignButton = uibutton(app.profilesTab, 'push');
            app.alignButton.ButtonPushedFcn = createCallbackFcn(app, @alignButtonPushed, true);
            app.alignButton.Text = 'align';
            app.fitButton = uibutton(app.profilesTab, 'push');
            app.fitButton.ButtonPushedFcn = createCallbackFcn(app, @fitButtonPushed, true);
            app.fitButton.Text = 'fit';
            app.MeanEditFieldLabel = uilabel(app.profilesTab);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.profilesTab, 'numeric');
            app.MeanEditField_2Label = uilabel(app.profilesTab);
            app.MeanEditField_2Label.Text = 'Mean';
            app.MeanEditField_2 = uieditfield(app.profilesTab, 'numeric');
            app.MeanEditField_3Label = uilabel(app.profilesTab);
            app.MeanEditField_3Label.Text = 'Mean';
            app.MeanEditField_3 = uieditfield(app.profilesTab, 'numeric');
            app.EditFieldLabel = uilabel(app.profilesTab);
            app.EditFieldLabel.Text = '+/-';
            app.sigmaEditField = uieditfield(app.profilesTab, 'numeric');
            app.EditField_2Label = uilabel(app.profilesTab);
            app.EditField_2Label.Text = '+/-';
            app.sigmaEditField_2 = uieditfield(app.profilesTab, 'numeric');
            app.EditField_3Label = uilabel(app.profilesTab);
            app.EditField_3Label.Text = '+/-';
            app.sigmaEditField_3 = uieditfield(app.profilesTab, 'numeric');
            app.extractandalignButton = uibutton(app.profilesTab, 'push');
            app.extractandalignButton.ButtonPushedFcn = createCallbackFcn(app, @extractandalignButtonPushed, true);
            app.extractandalignButton.Text = 'extract and align';
            app.addtostatButton = uibutton(app.profilesTab, 'push');
            app.addtostatButton.ButtonPushedFcn = createCallbackFcn(app, @addtostatButtonPushed, true);
            app.addtostatButton.Text = 'add to stat';
            app.CoMradiusAEditFieldLabel = uilabel(app.profilesTab);
            app.CoMradiusAEditFieldLabel.Text = 'CoM radius (A)';
            app.CoMradiusAEditField = uieditfield(app.profilesTab, 'numeric');
            app.NEditField_4Label = uilabel(app.profilesTab);
            app.NEditField_4Label.Text = 'N';
            app.NEditField_4 = uieditfield(app.profilesTab, 'numeric');
            app.MembraneSpinnerLabel = uilabel(app.profilesTab);
            app.MembraneSpinnerLabel.Text = 'Membrane';
            app.MembraneSpinner = uispinner(app.profilesTab);
            app.MembraneSpinner.Limits = [0 Inf];
            app.MembraneSpinner.ValueChangedFcn = createCallbackFcn(app, @MembraneSpinnerValueChanged, true);
            app.autosaveCheckBox = uicheckbox(app.profilesTab);
            app.autosaveCheckBox.Text = 'autosave';
            app.showlinesegmentsCheckBox = uicheckbox(app.profilesTab);
            app.showlinesegmentsCheckBox.Text = 'show line segments';
            app.ImageEditFieldLabel = uilabel(app.profilesTab);
            app.ImageEditFieldLabel.Text = 'Image:';
            app.ImageEditField = uieditfield(app.profilesTab, 'text');
            app.refineButton = uibutton(app.profilesTab, 'push');
            app.refineButton.ButtonPushedFcn = createCallbackFcn(app, @refineButtonPushed, true);
            app.refineButton.Text = 'refine';
            app.dEditFieldLabel = uilabel(app.profilesTab);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.profilesTab, 'numeric');
            app.dEditField_2Label = uilabel(app.profilesTab);
            app.dEditField_2Label.Text = 'd';
            app.dEditField_2 = uieditfield(app.profilesTab, 'numeric');
            app.dEditField_3Label = uilabel(app.profilesTab);
            app.dEditField_3Label.Text = 'd';
            app.dEditField_3 = uieditfield(app.profilesTab, 'numeric');
            app.thicknessTab = uitab(app.TabGroup3);
            app.thicknessTab.Title = 'thickness';
            app.UIAxes_ThicknessProfile1 = uiaxes(app.thicknessTab);
            title(app.UIAxes_ThicknessProfile1, 'Thickness profile 1')
            xlabel(app.UIAxes_ThicknessProfile1, 'X')
            ylabel(app.UIAxes_ThicknessProfile1, 'd')
            zlabel(app.UIAxes_ThicknessProfile1, 'Z')
            app.UIAxes_ThicknessProfile2 = uiaxes(app.thicknessTab);
            title(app.UIAxes_ThicknessProfile2, 'thickness profile 2')
            xlabel(app.UIAxes_ThicknessProfile2, 'X')
            ylabel(app.UIAxes_ThicknessProfile2, 'Y')
            zlabel(app.UIAxes_ThicknessProfile2, 'Z')
            app.UIAxes_ThicknessProfile3 = uiaxes(app.thicknessTab);
            title(app.UIAxes_ThicknessProfile3, 'Thickness profile 2')
            xlabel(app.UIAxes_ThicknessProfile3, 'X')
            ylabel(app.UIAxes_ThicknessProfile3, 'Y')
            zlabel(app.UIAxes_ThicknessProfile3, 'Z')
            app.UIAxes_AllProfilesTrans1 = uiaxes(app.thicknessTab);
            title(app.UIAxes_AllProfilesTrans1, 'All Profiles')
            xlabel(app.UIAxes_AllProfilesTrans1, 'X')
            ylabel(app.UIAxes_AllProfilesTrans1, 'Y')
            zlabel(app.UIAxes_AllProfilesTrans1, 'Z')
            app.UIAxes_Exceptions1 = uiaxes(app.thicknessTab);
            title(app.UIAxes_Exceptions1, 'Exceptions1')
            ylabel(app.UIAxes_Exceptions1, '+/-')
            app.UIAxes_Confidence1 = uiaxes(app.thicknessTab);
            title(app.UIAxes_Confidence1, 'Confidence range >')
            ylabel(app.UIAxes_Confidence1, '+/-')
            app.UIAxes_Confidence2 = uiaxes(app.thicknessTab);
            title(app.UIAxes_Confidence2, 'Confidence range >')
            ylabel(app.UIAxes_Confidence2, '+/-')
            app.UIAxes_Exceptions2 = uiaxes(app.thicknessTab);
            title(app.UIAxes_Exceptions2, 'Exceptions1')
            ylabel(app.UIAxes_Exceptions2, '+/-')
            app.UIAxes_AllProfilesTrans2 = uiaxes(app.thicknessTab);
            title(app.UIAxes_AllProfilesTrans2, 'All Profiles')
            xlabel(app.UIAxes_AllProfilesTrans2, 'X')
            ylabel(app.UIAxes_AllProfilesTrans2, 'Y')
            zlabel(app.UIAxes_AllProfilesTrans2, 'Z')
            app.UIAxes_Confidence3 = uiaxes(app.thicknessTab);
            title(app.UIAxes_Confidence3, 'Confidence range >')
            ylabel(app.UIAxes_Confidence3, '+/-')
            app.UIAxes_Exceptions3 = uiaxes(app.thicknessTab);
            title(app.UIAxes_Exceptions3, 'Exceptions1')
            ylabel(app.UIAxes_Exceptions3, '+/-')
            app.UIAxes_AllProfilesTrans3 = uiaxes(app.thicknessTab);
            title(app.UIAxes_AllProfilesTrans3, 'All Profiles')
            xlabel(app.UIAxes_AllProfilesTrans3, 'X')
            ylabel(app.UIAxes_AllProfilesTrans3, 'Y')
            zlabel(app.UIAxes_AllProfilesTrans3, 'Z')
            app.maxConfidenceEditFieldLabel = uilabel(app.thicknessTab);
            app.maxConfidenceEditFieldLabel.Text = 'max Confidence';
            app.maxConfidenceEditField = uieditfield(app.thicknessTab, 'numeric');
            app.maxConfidenceEditField.Limits = [0 Inf];
            app.maxConfidenceEditField.Value = 20;
            app.MembraneSpinner_2Label = uilabel(app.thicknessTab);
            app.MembraneSpinner_2Label.Text = 'Membrane';
            app.MembraneSpinner_2 = uispinner(app.thicknessTab);
            app.MembraneSpinner_2.Limits = [0 Inf];
            app.MembraneSpinner_2.ValueChangedFcn = createCallbackFcn(app, @MembraneSpinnerValueChanged, true);
            app.neglectprofileswexceptionsCheckBox = uicheckbox(app.thicknessTab);
            app.neglectprofileswexceptionsCheckBox.Text = 'neglect profiles w exceptions';
            app.includedLampLabel = uilabel(app.thicknessTab);
            app.includedLampLabel.Text = ' included';
            app.includedLamp = uilamp(app.thicknessTab);
            app.Tag1excludeCheckBox = uicheckbox(app.thicknessTab);
            app.Tag1excludeCheckBox.ValueChangedFcn = createCallbackFcn(app, @Tag1excludeCheckBoxValueChanged, true);
            app.Tag1excludeCheckBox.Text = 'Tag 1:  exclude';
            app.avglengthEditFieldLabel = uilabel(app.thicknessTab);
            app.avglengthEditFieldLabel.Text = 'avg length';
            app.avglengthEditField = uieditfield(app.thicknessTab, 'numeric');
            app.avglengthEditField.Limits = [0 Inf];
            app.avglengthEditField.Value = 5;
            app.statisticTab = uitab(app.TabGroup3);
            app.statisticTab.Title = 'statistic';
            app.UIAxes_Hist1_2 = uiaxes(app.statisticTab);
            title(app.UIAxes_Hist1_2, 'Thicknesses all 1')
            xlabel(app.UIAxes_Hist1_2, 'd')
            ylabel(app.UIAxes_Hist1_2, '#')
            zlabel(app.UIAxes_Hist1_2, 'Z')
            app.UIAxes_Hist2_2 = uiaxes(app.statisticTab);
            title(app.UIAxes_Hist2_2, 'Thicknesses all 2')
            xlabel(app.UIAxes_Hist2_2, 'd')
            ylabel(app.UIAxes_Hist2_2, '#')
            zlabel(app.UIAxes_Hist2_2, 'Z')
            app.UIAxes_Hist3_2 = uiaxes(app.statisticTab);
            title(app.UIAxes_Hist3_2, 'Thicknesses all 3')
            xlabel(app.UIAxes_Hist3_2, 'd')
            ylabel(app.UIAxes_Hist3_2, '#')
            zlabel(app.UIAxes_Hist3_2, 'Z')
            app.UIAxes_Membranes1 = uiaxes(app.statisticTab);
            title(app.UIAxes_Membranes1, 'Membranes 1')
            xlabel(app.UIAxes_Membranes1, 'r')
            ylabel(app.UIAxes_Membranes1, 'd')
            zlabel(app.UIAxes_Membranes1, 'Z')
            app.UIAxes_Membranes2 = uiaxes(app.statisticTab);
            title(app.UIAxes_Membranes2, 'Membranes 2')
            xlabel(app.UIAxes_Membranes2, 'r')
            ylabel(app.UIAxes_Membranes2, 'd')
            zlabel(app.UIAxes_Membranes2, 'Z')
            app.UIAxes_Membranes3 = uiaxes(app.statisticTab);
            title(app.UIAxes_Membranes3, 'Membranes 3')
            xlabel(app.UIAxes_Membranes3, 'r')
            ylabel(app.UIAxes_Membranes3, 'd')
            zlabel(app.UIAxes_Membranes3, 'Z')
            app.UIAxes_Avgavg1 = uiaxes(app.statisticTab);
            title(app.UIAxes_Avgavg1, 'Avg of avg 1')
            xlabel(app.UIAxes_Avgavg1, 'd')
            ylabel(app.UIAxes_Avgavg1, '#')
            zlabel(app.UIAxes_Avgavg1, 'Z')
            app.UIAxes_Avgavg2 = uiaxes(app.statisticTab);
            title(app.UIAxes_Avgavg2, 'Avg of avg 2')
            xlabel(app.UIAxes_Avgavg2, 'd')
            ylabel(app.UIAxes_Avgavg2, '#')
            zlabel(app.UIAxes_Avgavg2, 'Z')
            app.UIAxes_Avgavg3 = uiaxes(app.statisticTab);
            title(app.UIAxes_Avgavg3, 'Avg of avg 3')
            xlabel(app.UIAxes_Avgavg3, 'd')
            ylabel(app.UIAxes_Avgavg3, '#')
            zlabel(app.UIAxes_Avgavg3, 'Z')
            app.ResultsEditFieldLabel = uilabel(app.statisticTab);
            app.ResultsEditFieldLabel.Text = 'Results:';
            app.ResultsEditField = uieditfield(app.statisticTab, 'text');
            app.NEditFieldLabel = uilabel(app.statisticTab);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.statisticTab, 'numeric');
            app.FittedEditFieldLabel = uilabel(app.statisticTab);
            app.FittedEditFieldLabel.Text = 'Fitted:';
            app.FittedEditField = uieditfield(app.statisticTab, 'numeric');
            app.SegmentsEditFieldLabel = uilabel(app.statisticTab);
            app.SegmentsEditFieldLabel.Text = 'Segments:';
            app.SegmentsEditField = uieditfield(app.statisticTab, 'numeric');
            app.SaveButton = uibutton(app.statisticTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MeanEditField_4Label = uilabel(app.statisticTab);
            app.MeanEditField_4Label.Text = 'Mean';
            app.MeanEditField_4 = uieditfield(app.statisticTab, 'numeric');
            app.stdvLabel = uilabel(app.statisticTab);
            app.stdvLabel.Text = 'stdv';
            app.sigmaEditField_4 = uieditfield(app.statisticTab, 'numeric');
            app.FittedEditField_2Label = uilabel(app.statisticTab);
            app.FittedEditField_2Label.Text = 'Fitted:';
            app.FittedEditField_2 = uieditfield(app.statisticTab, 'numeric');
            app.NEditField_2Label = uilabel(app.statisticTab);
            app.NEditField_2Label.Text = 'N';
            app.NEditField_2 = uieditfield(app.statisticTab, 'numeric');
            app.SegmentsEditField_2Label = uilabel(app.statisticTab);
            app.SegmentsEditField_2Label.Text = 'Segments:';
            app.SegmentsEditField_2 = uieditfield(app.statisticTab, 'numeric');
            app.FittedEditField_3Label = uilabel(app.statisticTab);
            app.FittedEditField_3Label.Text = 'Fitted:';
            app.FittedEditField_3 = uieditfield(app.statisticTab, 'numeric');
            app.NEditField_3Label = uilabel(app.statisticTab);
            app.NEditField_3Label.Text = 'N';
            app.NEditField_3 = uieditfield(app.statisticTab, 'numeric');
            app.SegmentsEditField_3Label = uilabel(app.statisticTab);
            app.SegmentsEditField_3Label.Text = 'Segments:';
            app.SegmentsEditField_3 = uieditfield(app.statisticTab, 'numeric');
            app.stdvLabel_2 = uilabel(app.statisticTab);
            app.stdvLabel_2.Text = 'stdv';
            app.sigmaEditField_5 = uieditfield(app.statisticTab, 'numeric');
            app.MeanEditField_5Label = uilabel(app.statisticTab);
            app.MeanEditField_5Label.Text = 'Mean';
            app.MeanEditField_5 = uieditfield(app.statisticTab, 'numeric');
            app.stdvLabel_3 = uilabel(app.statisticTab);
            app.stdvLabel_3.Text = 'stdv';
            app.sigmaEditField_6 = uieditfield(app.statisticTab, 'numeric');
            app.MeanEditField_6Label = uilabel(app.statisticTab);
            app.MeanEditField_6Label.Text = 'Mean';
            app.MeanEditField_6 = uieditfield(app.statisticTab, 'numeric');
            app.batchfitButton = uibutton(app.statisticTab, 'push');
            app.batchfitButton.ButtonPushedFcn = createCallbackFcn(app, @batchfitButtonPushed, true);
            app.batchfitButton.Text = 'batch fit';
            app.Stat1LoadButton = uibutton(app.statisticTab, 'push');
            app.Stat1LoadButton.ButtonPushedFcn = createCallbackFcn(app, @Stat1LoadButtonPushed, true);
            app.Stat1LoadButton.Text = '>';
            app.Stat2LoadButton = uibutton(app.statisticTab, 'push');
            app.Stat2LoadButton.ButtonPushedFcn = createCallbackFcn(app, @Stat2LoadButtonPushed, true);
            app.Stat2LoadButton.Text = '>';
            app.Stat3LoadButton = uibutton(app.statisticTab, 'push');
            app.Stat3LoadButton.ButtonPushedFcn = createCallbackFcn(app, @Stat3LoadButtonPushed, true);
            app.Stat3LoadButton.Text = '>';
            app.Group1EditFieldLabel = uilabel(app.statisticTab);
            app.Group1EditFieldLabel.Text = 'Group 1';
            app.Group1EditField = uieditfield(app.statisticTab, 'text');
            app.Group2EditFieldLabel = uilabel(app.statisticTab);
            app.Group2EditFieldLabel.Text = 'Group 2';
            app.Group2EditField = uieditfield(app.statisticTab, 'text');
            app.Group2EditField_2Label = uilabel(app.statisticTab);
            app.Group2EditField_2Label.Text = 'Group 2';
            app.Group3EditField = uieditfield(app.statisticTab, 'text');
            app.CLEARButton = uibutton(app.statisticTab, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.RefitallCheckBox = uicheckbox(app.statisticTab);
            app.RefitallCheckBox.Text = '(Re-)fit all';
            app.SaveButton_stat1 = uibutton(app.statisticTab, 'push');
            app.SaveButton_stat1.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_stat1Pushed, true);
            app.SaveButton_stat1.Text = 'Save';
            app.SaveButton_stat2 = uibutton(app.statisticTab, 'push');
            app.SaveButton_stat2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_stat1Pushed, true);
            app.SaveButton_stat2.Text = 'Save';
            app.SaveButton_stat3 = uibutton(app.statisticTab, 'push');
            app.SaveButton_stat3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_stat1Pushed, true);
            app.SaveButton_stat3.Text = 'Save';
            app.updateButton = uibutton(app.statisticTab, 'push');
            app.updateButton.ButtonPushedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.updateButton.Text = 'update';
            app.Group1LampLabel = uilabel(app.statisticTab);
            app.Group1LampLabel.Text = 'Group 1';
            app.Group1Lamp = uilamp(app.statisticTab);
            app.Group2LampLabel = uilabel(app.statisticTab);
            app.Group2LampLabel.Text = 'Group 2';
            app.Group2Lamp = uilamp(app.statisticTab);
            app.Group3LampLabel = uilabel(app.statisticTab);
            app.Group3LampLabel.Text = 'Group 3';
            app.Group3Lamp = uilamp(app.statisticTab);
            app.poolresultsCheckBox = uicheckbox(app.statisticTab);
            app.poolresultsCheckBox.Text = 'pool results';
            app.ToolsTab = uitab(app.TabGroup3);
            app.ToolsTab.Title = 'Tools';
            app.UpdatewithnewMagnificationButton = uibutton(app.ToolsTab, 'push');
            app.UpdatewithnewMagnificationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatewithnewMagnificationButtonPushed, true);
            app.UpdatewithnewMagnificationButton.Text = 'Update with new Magnification';
            app.newmagnificationApxEditFieldLabel = uilabel(app.ToolsTab);
            app.newmagnificationApxEditFieldLabel.Text = 'new magnification (A/px)';
            app.newmagnificationApxEditField = uieditfield(app.ToolsTab, 'numeric');
            app.newmagnificationApxEditField.Limits = [0.1 Inf];
            app.newmagnificationApxEditField.Value = 1.9;
            app.StatusEditFieldLabel = uilabel(app.RightPanel);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.RightPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CryoReader1_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end